classdef simAppAux < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HeaderLabel                matlab.ui.control.Label
        GridLayout                 matlab.ui.container.GridLayout
        PlanPanel                  matlab.ui.container.Panel
        PlanPanelGridLayout        matlab.ui.container.GridLayout
        dummyCodedCovarsCheckBox   matlab.ui.control.CheckBox
        sitesListBox               matlab.ui.control.ListBox
        sitesListBoxLabel          matlab.ui.control.Label
        NRepsSpinner               matlab.ui.control.Spinner
        NRepsSpinnerLabel          matlab.ui.control.Label
        add2originalCheckBox       matlab.ui.control.CheckBox
        conditionalSimCheckBox     matlab.ui.control.CheckBox
        conditionsVarListBox       matlab.ui.control.ListBox
        conditionsVarListBoxLabel  matlab.ui.control.Label
        SimSpinner                 matlab.ui.control.Spinner
        SimSpinnerLabel            matlab.ui.control.Label
        OkButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app simSample_App2
        EditFields
        add2orig
        NGroups
        NSims
        simGrid
        NReps
        CovarsNames
        siteDummies = 0
        simCond = -1
        GridEditFields
        Labels
    end
    
    methods (Access = private)
        
        function updateGrid(app, NGroups, NSims, simGrid)
            
            
            app.PlanPanelGridLayout.ColumnWidth = repelem("1x",NSims +1);
            
 
            delete(app.PlanPanelGridLayout.Children)


            app.GridEditFields = [];
            app.Labels = [];
            counter = 0;
            for sim = 1:NSims
                for group = 1:NGroups
                    counter = counter + 1;
                    thisEditField = uieditfield(app.PlanPanelGridLayout,'numeric', 'Limits', [0 Inf]);
                    thisEditField.Layout.Row = group + 1; 
                    thisEditField.Layout.Column = sim + 1;
                    app.GridEditFields(counter) = thisEditField.Value; 

                    % create column and row labels
                    app.Labels(counter).RowLabel = uilabel(app.PlanPanelGridLayout,'Text', sprintf('Group %d', group));
                    app.Labels(counter).RowLabel.Layout.Row = group + 1;
                    app.Labels(counter).RowLabel.Layout.Column = 1; 
                    app.Labels(counter).ColumnLabel = uilabel(app.PlanPanelGridLayout,'Text', sprintf('Sim. %d', sim));
                    app.Labels(counter).ColumnLabel.Layout.Row = 1;
                    app.Labels(counter).ColumnLabel.Layout.Column = sim + 1; 

                end
            end

            if ~isempty(simGrid)
                simVec = reshape(simGrid,1,numel(simGrid));
                simVecpadded= zeros(1,length(app.GridEditFields));
                simVecpadded(1,1:length(simVec)) = simVec;
                fact = 0;
                for i=1:length(app.GridEditFields)
                    thisEditField = app.PlanPanelGridLayout.Children(1+fact*3);
                    thisEditField.Value = simVecpadded(i);
                    fact = fact + 1; 
                end
            end
            drawnow;
        end
        
      
        function [app, popuplist, popupitems] = PopulateSitesCovarsDropDown(app)
            
            popuplist = cell(1, numel(app.CovarsNames));
            for i=1:numel(app.CovarsNames)
                popuplist{i} = sprintf('%g: %s', i, app.CovarsNames{i});
            end
            popupitems = 1:numel(app.CovarsNames);
        
        end

        function [app, popuplist, popupitems] = PopulateConditionalDropDown(app)
            
            if app.CallingApp.NM.modeflag == 'classification'
                popuplist = cell(1, 1 + numel(app.CovarsNames));
                popupitems = 0:numel(app.CovarsNames);
                names = cat(1,{'label'}, app.CovarsNames);
            else % 'regression'
                popuplist = cell(1, numel(app.CovarsNames));
                popupitems = 1:numel(app.CovarsNames);
                names = app.CovarsNames;
            end
            
            for i=1:numel(popupitems)
                popuplist{i} = sprintf('%g: %s', i, string(names(i)));
            end
            
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NSims, NGroups, simGrid, add2orig, NReps, covarsnames, simCond)
            app.CallingApp = mainapp;
            app.add2originalCheckBox.Value = add2orig; % set check box label to value it has previously been set to
            app.SimSpinner.Value = NSims; 
            app.NGroups = NGroups;
            app.NSims = NSims; 
            app.simGrid = simGrid;
            app.CovarsNames = covarsnames;
            app.NRepsSpinner.Value = NReps;
            app.simCond = simCond;
            if ~isempty(simCond) & simCond > -1
                app.conditionalSimCheckBox.Value = 1;
                app.conditionsVarListBox.Visible = 'on';
                app.conditionsVarListBox.Enable = 'on';
                app.conditionsVarListBoxLabel.Visible = 'on';
                [app, app.conditionsVarListBox.Items, app.conditionsVarListBox.ItemsData] = PopulateConditionalDropDown(app);
                app.conditionsVarListBox.Value = simCond;
                app.conditionalSimCheckBox.Value = 1;
            end

            
            if ~isempty(covarsnames)
                app.dummyCodedCovarsCheckBox.Visible = 'on';
                app.dummyCodedCovarsCheckBox.Enable = 'on';
            end


            % intitialize grid
            updateGrid(app, NGroups, NSims, simGrid);
         
        end

        % Value changed function: SimSpinner
        function SimSpinnerValueChanged(app, event)
            app.NSims = app.SimSpinner.Value;
            if app.conditionalSimCheckBox.Value == 0
                app.NGroups = 1;
            elseif app.conditionsVarListBox.Value == 0 % label
                app.NGroups = length(unique(app.CallingApp.NM.label));
            else
                app.NGroups = length(unique(app.CallingApp.NM.covars(:,app.conditionsVarListBox.Value)));
            end
            updateGrid(app, app.NGroups, app.NSims, app.simGrid);
       
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            values = zeros(1,numel(app.GridEditFields));
            
            fact = 0;
            for i=1:length(app.GridEditFields)
                thisEditField = app.PlanPanelGridLayout.Children(1+fact*3);
                values(i) = thisEditField.Value;
                fact = fact+1;
            end
            
            simGrid = reshape(values, [app.NGroups,app.NSims]);
            %simGrid = simGrid';
            app.add2orig = app.add2originalCheckBox.Value;

            if app.dummyCodedCovarsCheckBox.Value == 1
                app.siteDummies = app.sitesListBox.Value;
            end

            if app.conditionalSimCheckBox.Value == 1
                app.simCond = app.conditionsVarListBox.Value;
               % condName = app.conditionsVarListBox.ItemsData;
            end

            updateSimPlan(app.CallingApp, simGrid, app.add2orig, app.NRepsSpinner.Value, app.siteDummies, app.simCond);

            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SetupButton.Enable = 'on';
            app.CallingApp.SimulateButton_post.Enable = 'on';
            
            
            delete(app);
        end

        % Value changed function: dummyCodedCovarsCheckBox
        function dummyCodedCovarsCheckBoxValueChanged(app, event)
            value = app.dummyCodedCovarsCheckBox.Value;
            if value == 1
                app.sitesListBox.Visible = 'on';
                app.sitesListBox.Enable = 'on';
                app.sitesListBoxLabel.Visible = 'on';
                app.sitesListBoxLabel.Enable = 'on';
                [app, app.sitesListBox.Items, app.sitesListBox.ItemsData] = PopulateSitesCovarsDropDown(app);
            else
                app.sitesListBox.Visible = 'off';
                app.sitesListBox.Enable = 'off';
                app.sitesListBoxLabel.Visible = 'off';
                app.sitesListBoxLabel.Enable = 'off';
            end
            drawnow;
        end

        % Value changed function: conditionalSimCheckBox
        function conditionalSimCheckBoxValueChanged(app, event)
            value = app.conditionalSimCheckBox.Value;
            if value == 1
                app.conditionsVarListBox.Visible = 'on';
                app.conditionsVarListBox.Enable = 'on';
                app.conditionsVarListBoxLabel.Visible = 'on';
                app.conditionsVarListBoxLabel.Enable = 'on';
                [app, app.conditionsVarListBox.Items, app.conditionsVarListBox.ItemsData] = PopulateConditionalDropDown(app);
            else
                SimSpinnerValueChanged(app); 
                app.conditionsVarListBox.Visible = 'off';
                app.conditionsVarListBox.Enable = 'off';
                app.conditionsVarListBoxLabel.Visible = 'off';
                app.conditionsVarListBoxLabel.Enable = 'off';
                app.simCond = -1;
            end
            drawnow;
        end

        % Value changed function: conditionsVarListBox
        function conditionsVarListBoxValueChanged(app, event)

            if app.conditionalSimCheckBox.Value == 0
                app.NGroups = 1;
            elseif app.conditionsVarListBox.Value == 0 % label
                app.NGroups = length(unique(app.CallingApp.NM.label));
            else
                app.NGroups = length(unique(app.CallingApp.NM.covars(:,app.conditionsVarListBox.Value)));
            end
            updateGrid(app, app.NGroups, app.NSims, app.simGrid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.SimSpinnerLabel = uilabel(app.GridLayout);
            app.SimSpinnerLabel.Text = 'N simulation rounds';
            app.SimSpinner = uispinner(app.GridLayout);
            app.SimSpinner.Limits = [1 Inf];
            app.SimSpinner.ValueChangedFcn = createCallbackFcn(app, @SimSpinnerValueChanged, true);
            app.SimSpinner.Value = 1;
            app.conditionsVarListBoxLabel = uilabel(app.GridLayout);
            app.conditionsVarListBoxLabel.Enable = 'off';
            app.conditionsVarListBoxLabel.Visible = 'off';
            app.conditionsVarListBoxLabel.Text = 'Select group-defining variable';
            app.conditionsVarListBox = uilistbox(app.GridLayout);
            app.conditionsVarListBox.ValueChangedFcn = createCallbackFcn(app, @conditionsVarListBoxValueChanged, true);
            app.conditionsVarListBox.Visible = 'off';
            app.conditionalSimCheckBox = uicheckbox(app.GridLayout);
            app.conditionalSimCheckBox.ValueChangedFcn = createCallbackFcn(app, @conditionalSimCheckBoxValueChanged, true);
            app.conditionalSimCheckBox.Tooltip = {'if you select this option, you can specify the number of cases you want to simulate dependent on groups defined by the label (in case of classification) or a categorical covariate. '; ''; 'At the moment, NeuroMiner does not check, whether a variable is categorical, so make sure you do! '};
            app.conditionalSimCheckBox.Text = 'simulate dependent on variable ';
            app.add2originalCheckBox = uicheckbox(app.GridLayout);
            app.add2originalCheckBox.Text = 'add to original data';
            app.NRepsSpinnerLabel = uilabel(app.GridLayout);
            app.NRepsSpinnerLabel.Text = 'N repetitions per simulation';
            app.NRepsSpinner = uispinner(app.GridLayout);
            app.NRepsSpinner.Limits = [1 Inf];
            app.NRepsSpinner.Value = 1;
            app.sitesListBoxLabel = uilabel(app.GridLayout);
            app.sitesListBoxLabel.Enable = 'off';
            app.sitesListBoxLabel.Visible = 'off';
            app.sitesListBoxLabel.Text = 'Select dummy-coded covariates';
            app.sitesListBox = uilistbox(app.GridLayout);
            app.sitesListBox.Multiselect = 'on';
            app.sitesListBox.Enable = 'off';
            app.sitesListBox.Visible = 'off';
            app.sitesListBox.Value = {'Item 1'};
            app.dummyCodedCovarsCheckBox = uicheckbox(app.GridLayout);
            app.dummyCodedCovarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @dummyCodedCovarsCheckBoxValueChanged, true);
            app.dummyCodedCovarsCheckBox.Text = 'dummy-coded covariate';
            app.PlanPanel = uipanel(app.GridLayout);
            app.PlanPanel.Title = 'Rows = Groups; Columns = Simulation rounds';
            app.PlanPanelGridLayout = uigridlayout(app.PlanPanel);
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Setup the simulation rounds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simAppAux(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end