classdef appMLI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        FMLIButton                     matlab.ui.control.Button
        FranksButton                   matlab.ui.control.Button
        EButton                        matlab.ui.control.Button
        FeaturesDropDown               matlab.ui.control.DropDown
        FeaturesDropDownLabel          matlab.ui.control.Label
        AnalysisDropDown               matlab.ui.control.DropDown
        AnalysisDropDownLabel          matlab.ui.control.Label
        LabelDropDown                  matlab.ui.control.DropDown
        LabelDropDownLabel             matlab.ui.control.Label
        BinaryclassifierDropDown       matlab.ui.control.DropDown
        BinaryclassifierDropDownLabel  matlab.ui.control.Label
        ModalityDropDown               matlab.ui.control.DropDown
        ModalityDropDownLabel          matlab.ui.control.Label
        SortfeaturesCheckBox           matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        SelectsubjectDropDown          matlab.ui.control.DropDown
        SelectsubjectDropDownLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TitleLabel                     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cases 
        clrswp
        Model
        modeflag
        datadescriptor
        MLI
        MLImethod
        curCaseIdx
        featnames
        labels
        groupnames
        Ys
        curHandles
        curAnalysis
        curLabel
        curClassifier
        curModalityType
        curModality
        curFeatures
        case_groupname
        modestr
        niiViewer
    end
    
    properties (Access = public)
        stMLI
        CLP
        CLR1
        CLR2
    end

    methods (Access = public)
        
        function updateFcn(app,newHandles)
            if ~isequal(app.curHandles, newHandles)
                app.startupFcn(newHandles)
            end
        end
    end
    
    methods (Access = private)
        
        function app = PopulateAnalysisDropDown(app, handles)
            AS = nk_GetAnalysisStatus(handles.NM);
            idx = AS.analyses_interpreted; f_idx = find(idx);
            popuplist = cell(1, numel(f_idx));
            for i=1:numel(f_idx)
                popuplist{i} = sprintf('#%g: %s', f_idx(i), handles.NM.analysis{f_idx(i)}.id);
            end
            popupitems = f_idx;
            app.AnalysisDropDown.Items = popuplist;
            app.AnalysisDropDown.ItemsData = popupitems;
            if isfield(handles,'curranal'), app.AnalysisDropDown.Value = handles.curranal; end
        end
        
        function app = PopulateBinaryClassifierDropDown(app, handles)
            if strcmp(handles.modeflag,'classification')
                nclass = numel(handles.MLIdata.Label(1,1).BinResults);
                curranal = app.AnalysisDropDown.Value; popuplist = cell(1,nclass);
                for i=1:nclass
                    popuplist{i} = sprintf('#%g: %s', i, handles.NM.analysis{curranal}.params.cv.class{1,1}{i}.groupdesc);
                end
                app.BinaryclassifierDropDown.Items = popuplist;
                app.BinaryclassifierDropDown.ItemsData = 1:nclass;
                app.modeflag = 'classification';
            else
                app.BinaryclassifierDropDown.Items = {'Regressor'};
                app.BinaryclassifierDropDown.ItemsData = 1;
                app.BinaryclassifierDropDown.Enable = "off";
                app.modeflag = 'regression';
            end
            if isfield(handles,'curclass'), app.BinaryclassifierDropDown.Value = handles.curclass; end

        end

        function app = PopulateModalityDropDown(app, handles)

            nM = length(handles.MLIdata.Label(1,1).(app.modestr)(1).Modality);
            curranal = app.AnalysisDropDown.Value;
            if nM>1
                app.ModalityDropDown.Enable = "on"; popuplist = cell(1,nM);               
                for i = 1:nM
                    popuplist{i} = sprintf('#%g: %s', handles.NM.analysis{curranal}.params.TrainParam.FUSION.M(i), ...
                        handles.NM.analysis{curranal}.params.datadescriptor{1,i}.desc);
                end
                app.ModalityDropDown.Items = popuplist;
                app.ModalityDropDown.ItemsData = 1:nM;
            else
                app.ModalityDropDown.Items = {'Single Modality'};
                app.ModalityDropDown.ItemsData = 1;
                app.ModalityDropDown.Enable = 'off';
            end
        end

        function app = PopulateLabelDropDown(app, handles)
            nL = size(handles.MLIdata.Label,2);
            if nL>1
                app.LabelDropDown.Enable = "on"; popuplist = cell(1,nL);               
                for i = 1:nL
                    popuplist{i} = sprintf('#%g: %s', i, handles.NM.labelnames{i});
                end
                app.LabelDropDown.Items = popuplist;
                app.Label.ItemsData = 1:nL;
            else
                app.LabelDropDown.Items = {'Single Label'};
                app.LabelDropDown.ItemsData = 1;
                app.LabelDropDown.Enable = 'off';
            end
        end

        function app = PopulateSubjectsDropDown(app)
            app.SelectsubjectDropDown.Items = app.cases;
            app.SelectsubjectDropDown.ItemsData = [app.cases];
        end

    function app = PopulateFeaturesDropDown(app, handles)

            modality_type = handles.MLIdata.Label(1,1).(app.modestr)(1).Modality.modality_type;
            if ~modality_type
                app.FeaturesDropDown.Enable="on";
                app.SortfeaturesCheckBox.Enable="on";
                nfeats = length(handles.MLIdata.Label(1,1).(app.modestr)(1).Modality.modality_featnames);
                perpage = 20;
                if nfeats > perpage
                    vec = 1:perpage:nfeats;
                    if vec(end) < nfeats
                        vec(end+1) = nfeats; 
                    end
                    app.FeaturesDropDown.Enable = "on"; popuplist = cell(1,size(vec,2));  
                    popupitem = cell(1,size(vec,2));
                    for i=1:numel(vec)-1
                        popuplist{i} = sprintf('%g:%g',vec(i),vec(i+1));
                        popupitem{i} = vec(i):vec(i+1);
                    end
                    popuplist{end} = sprintf('%g:%g',vec(1),vec(end));
                    popupitem{end} = vec(1):vec(end);
                    app.FeaturesDropDown.Items = popuplist;
                    app.FeaturesDropDown.ItemsData = popupitem;
                else
                    popuplist{1} = sprintf('1:%g',nfeats);
                    app.FeaturesDropDown.Items = popuplist{1};
                    app.FeaturesDropDown.ItemsData = {1:nfeats};
                    app.FeaturesDropDown.Enable = "off"; 
                end
            else
                % Imaging analysis
                app.FeaturesDropDown.Enable="off";
                app.SortfeaturesCheckBox.Enable="off";
            end
        end

        function app = SetMLI(app, handles)
            app.curAnalysis = app.AnalysisDropDown.Value;
            app.curLabel = app.LabelDropDown.Value;
            app.curClassifier = app.BinaryclassifierDropDown.Value;
            app.MLImethod = handles.NM.analysis{1, app.curAnalysis}.params.TrainParam.MLI.method;
            switch handles.NM.analysis{app.curAnalysis}.params.TrainParam.FUSION.flag
                case 3
                    app.MLI = handles.NM.analysis{app.curAnalysis}.MLI.Label(app.ModalityDropDown.Value, app.curLabel).(app.modestr)(app.curClassifier).Modality;
                otherwise
                    app.MLI = handles.NM.analysis{app.curAnalysis}.MLI.Label(app.curLabel).(app.modestr)(app.curClassifier).Modality(app.ModalityDropDown.Value);
            end
            if ~app.MLI.modality_type
                app.curFeatures = app.FeaturesDropDown.Value;
            else
                app.curFeatures = 1:size(app.MLI.Y_mapped,2);
            end
            app.MLI.Y_mapped = app.MLI.Y_mapped(:,app.curFeatures);
            app.MLI.Y_mapped_ciu = app.MLI.Y_mapped_ciu(:,app.curFeatures);
            app.MLI.Y_mapped_cil = app.MLI.Y_mapped_cil(:,app.curFeatures);
            app.MLI.Y_mapped_std = app.MLI.Y_mapped_std(:,app.curFeatures);
            if isequal(app.MLImethod, 'shapley')
                app.MLI.ShapleyValues = app.MLI.ShapleyValues(:,app.curFeatures);
            end
            app.curModality = app.MLI.modality_num;
            app.curModalityType = app.MLI.modality_type;
            if ~app.MLI.modality_type
                app.featnames = handles.MLIdata.Label.BinResults(1).Modality.modality_featnames(app.curFeatures);
            end
            app.labels = handles.NM.label(:,app.curLabel);
            app.datadescriptor = handles.NM.datadescriptor{app.curModality};
            app = PopulateFeaturesDropDown(app, handles);
        end
        
        function app = PlotMLI(app)
            
            curCaseID = app.SelectsubjectDropDown.ItemsData{app.curCaseIdx};

            switch app.modeflag
                case 'classification'
                    app.TitleLabel.Text = sprintf('Predictive profile of subject ''%s'' [Group: %s]', curCaseID, app.case_groupname);
                case 'regression'
                    app.TitleLabel.Text = sprintf('Predictive profile of subject ''%s''', curCaseID);
            end

            if app.curModalityType %1 = imaging

                app.SortfeaturesCheckBox.Visible = 0;
                app.FeaturesDropDown.Visible = 0;
                app.UIAxes.Visible = 0;
                app.UIAxes2.Visible = 0;
                nk_WriteVol( app.MLI.Y_mapped(app.curCaseIdx,:)','tempMLI', 2, ...
                    app.curHandles.NM.brainmask{app.curModality}, ...
                    app.curHandles.NM.badcoords{app.curModality}, ...
                    app.datadescriptor.threshval, char(app.datadescriptor.threshop),[],[],false);
               mon = get(0,'MonitorPositions');                  % [x y w h] rows for each monitor
                targetMonIdx = 1;
                try
                    % If you have an App Designer UIFigure, place the viewer on that monitor
                    hostFig = ancestor(app.UIFigure,'figure');    % or store your host figure handle differently
                    appPos  = getpixelposition(hostFig, true);    % absolute on-screen
                    appCtr  = [appPos(1)+appPos(3)/2, appPos(2)+appPos(4)/2];
                    % pick monitor whose rectangle contains appCtr
                    for i = 1:size(mon,1)
                        r = mon(i,:);
                        if appCtr(1) >= r(1) && appCtr(1) <= r(1)+r(3) && appCtr(2) >= r(2) && appCtr(2) <= r(2)+r(4)
                            targetMonIdx = i; break;
                        end
                    end
                catch
                    % fall back to primary monitor
                end
                r = mon(targetMonIdx,:);                          % monitor rect
                
                fw = round(r(3) * 2/3);                           % figure width  = 2/3 screen width
                fh = round(r(4) * 2/3);                           % figure height = 2/3 screen height
                fx = round(r(1) + (r(3)-fw)/2);                   % centered x
                fy = round(r(2) + (r(4)-fh)/2);                   % centered y
                
                % 3) Create classic figure: menus ON, toolbar OFF, dock OFF
                niifigure = figure( ...
                    'Name', sprintf('XAI viewer of case ''%s''', app.cases{app.curCaseIdx}), ...
                    'NumberTitle', 'off', ...
                    'MenuBar',     'figure', ...                  % keep menus
                    'ToolBar',     'none', ...                    % hide toolbar icons
                    'DockControls','off', ...
                    'Color',       [0.94 0.94 0.94], ...
                    'Units',       'pixels', ...
                    'Position',    [fx fy fw fh], ...
                    'Visible',     'on');                
                app.niiViewer = overlay_nifti_gui(app.curHandles.NM.defs.atlas_path, 'tempMLI.nii', niifigure, 0.75);
            
            elseif isequal(app.MLImethod, 'shapley')
                app.SortfeaturesCheckBox.Visible = 1;
                app.FeaturesDropDown.Visible = 1;
                app.UIAxes.Visible = 1;
                app.UIAxes2.Visible = 1;
                %app.UIAxes3.Visible = 0;
                %app.UIAxes4.Visible = 0;
                %app.UIAxes5.Visible = 0;
                cla(app.UIAxes);
                cla(app.UIAxes2);
                
                barShapley(app.curCaseIdx, app.MLI, ...
                    app.featnames, ...
                    app.Ys{1,app.curModality}, ...
                    app.SortfeaturesCheckBox.Value, ...
                    app.UIAxes, ...
                    app.UIAxes2, ...
                    app.CLR1, ...
                    app.CLR2);
            else
                app.SortfeaturesCheckBox.Visible = 1;
                app.FeaturesDropDown.Visible = 1;
                app.UIAxes.Visible = 1;
                app.UIAxes2.Visible = 1;
                %app.UIAxes3.Visible = 0;
                %app.UIAxes4.Visible = 0;
                %app.UIAxes5.Visible = 0;
                cla(app.UIAxes);
                cla(app.UIAxes2);
                
                barMLI(app.curCaseIdx, app.MLI, ...
                    app.featnames, ...
                    app.Ys{1,app.curModality}, ...
                    app.SortfeaturesCheckBox.Value, ...
                    app.UIAxes, ...
                    app.UIAxes2, ...
                    app.CLR1, ...
                    app.CLR2);
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles, varargin)

            app.curHandles = handles;
            %% Define colors
            app.clrswp = handles.tglClrSwp.Value;
            if app.clrswp
                g1 = 2; g2 = 1;
            else
                g1 = 1; g2 = 2;
            end
            switch handles.modeflag
                case 'classification'
                    app.modestr = 'BinResults';
                    app.CLR1 = handles.colptin(handles.BinClass{1}.groupind(g1),:);
                    if ~handles.BinClass{1}.one_vs_all
                        app.CLP = handles.colpt;
                        app.CLR2 = handles.colptin(handles.BinClass{1}.groupind(g2),:);
                    else
                        app.CLP = 'o';
                        app.CLR2 = rgb('DarkGrey');
                    end
                case 'regression'
                    app.modestr = 'RegrResults';
                    app.CLP = 'o';
                    app.CLR1 = rgb('DarkGrey');
                    app.CLR2 = rgb('DarkGrey');
            end
            
            app.cases = handles.subjects;
            app.groupnames = handles.NM.groupnames;
            app = PopulateAnalysisDropDown(app, handles);
            app = PopulateBinaryClassifierDropDown(app, handles);
            app = PopulateModalityDropDown(app, handles);
            app = PopulateLabelDropDown(app, handles);
            app = PopulateSubjectsDropDown(app);
            app = PopulateFeaturesDropDown(app, handles);
            app = SetMLI(app, handles);

            for n=1:numel(handles.NM.Y)
                if app.MLI.modality_num == n && isfield(app.MLI, 'refdataflag') && app.MLI.refdataflag && ~isempty(app.MLI.refdata)
                    app.Ys{n} = app.MLI.refdata;
                elseif ischar(handles.NM.Y{n})
                    dat = load(handles.NM.Y{n},'Y');
                    app.Ys{n} = dat.Y;
                else
                    app.Ys{n} = handles.NM.Y{n};
                end
            end

            DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
                
            drawnow
            
            if numel(varargin) == 0
                app.curCaseIdx = handles.selCase.Value;
                curCaseID = DDitemsdataMatChar{app.curCaseIdx};
            else
                app.curCaseIdx = varargin{1};
                curCaseID = DDitemsdataMatChar(app.curCaseIdx);
            end
            app.SelectsubjectDropDown.Value = curCaseID;
            g = app.labels(app.curCaseIdx);
            app.case_groupname = char(app.groupnames(g));
            PlotMLI(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            PlotMLI(app);
        end

        % Drop down opening function: SelectsubjectDropDown
        function SelectsubjectDropDownOpening(app, event)
           
        end

        % Value changed function: SelectsubjectDropDown
        function SelectsubjectDropDownValueChanged(app, event)
            curCaseID = app.SelectsubjectDropDown.Value;
            DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
            app.curCaseIdx = find(strcmp(DDitemsdataMatChar,curCaseID));
            g = app.labels(app.curCaseIdx);
            app.case_groupname = char(app.groupnames(g)); 
        end

        % Value changed function: ModalityDropDown
        function ModalityChanged(app, event)
            SetMLI(app, app.curHandles);
        end

        % Value changed function: BinaryclassifierDropDown
        function BinaryclassifierDropDownValueChanged(app, event)
            SetMLI(app, app.curHandles);
        end

        % Value changed function: LabelDropDown
        function LabelDropDownValueChanged(app, event)
            SetMLI(app, app.curHandles);
        end

        % Value changed function: AnalysisDropDown
        function AnalysisDropDownValueChanged(app, event)
            SetMLI(app, app.curHandles);
        end

        % Callback function
        function FeaturesDropDownOpening(app, event)
            
        end

        % Value changed function: FeaturesDropDown
        function FeaturesDropDownValueChanged(app, event)
            SetMLI(app, app.curHandles);
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            fn = ['Y_mapped_A' num2str(app.curAnalysis) '.csv'];
            fn_ciu = ['Y_mapped_A' num2str(app.curAnalysis) '_ciu.csv'];
            fn_cil = ['Y_mapped_A' num2str(app.curAnalysis) '_cil.csv'];
            fn_std = ['Y_mapped_A' num2str(app.curAnalysis) '_std.csv'];
            Y_mapped = array2table(app.MLI.Y_mapped, 'VariableNames', app.featnames, 'RowNames', app.cases);
            Y_mapped_ciu = array2table(app.MLI.Y_mapped_ciu, 'VariableNames', app.featnames, 'RowNames', app.cases);
            Y_mapped_cil = array2table(app.MLI.Y_mapped_cil, 'VariableNames', app.featnames, 'RowNames', app.cases);
            Y_mapped_std = array2table(app.MLI.Y_mapped_std, 'VariableNames', app.featnames, 'RowNames', app.cases);
            writetable(Y_mapped,fn,'WriteRowNames',true)
            writetable(Y_mapped_ciu,fn_ciu,'WriteRowNames',true)
            writetable(Y_mapped_cil,fn_cil,'WriteRowNames',true)
            writetable(Y_mapped_std,fn_std,'WriteRowNames',true)
        end

        % Callback function
        function FranksButtonPushed(app, event)
            figure
            PlotMLI(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {807, 807};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectsubjectDropDownLabel = uilabel(app.LeftPanel);
            app.SelectsubjectDropDownLabel.Text = 'Select subject';
            app.SelectsubjectDropDown = uidropdown(app.LeftPanel);
            app.SelectsubjectDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectsubjectDropDownOpening, true);
            app.SelectsubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectsubjectDropDownValueChanged, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SortfeaturesCheckBox = uicheckbox(app.LeftPanel);
            app.SortfeaturesCheckBox.Text = 'Sort features';
            app.ModalityDropDownLabel = uilabel(app.LeftPanel);
            app.ModalityDropDownLabel.Text = 'Modality';
            app.ModalityDropDown = uidropdown(app.LeftPanel);
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityChanged, true);
            app.BinaryclassifierDropDownLabel = uilabel(app.LeftPanel);
            app.BinaryclassifierDropDownLabel.Text = 'Binary classifier';
            app.BinaryclassifierDropDown = uidropdown(app.LeftPanel);
            app.BinaryclassifierDropDown.ValueChangedFcn = createCallbackFcn(app, @BinaryclassifierDropDownValueChanged, true);
            app.LabelDropDownLabel = uilabel(app.LeftPanel);
            app.LabelDropDown = uidropdown(app.LeftPanel);
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelDropDownValueChanged, true);
            app.AnalysisDropDownLabel = uilabel(app.LeftPanel);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.LeftPanel);
            app.AnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisDropDownValueChanged, true);
            app.FeaturesDropDownLabel = uilabel(app.LeftPanel);
            app.FeaturesDropDownLabel.Text = 'Features';
            app.FeaturesDropDown = uidropdown(app.LeftPanel);
            app.FeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturesDropDownValueChanged, true);
            app.EButton = uibutton(app.LeftPanel, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.FranksButton = uibutton(app.LeftPanel, 'push');
            app.FranksButton.Text = 'F (ranks)';
            app.FMLIButton = uibutton(app.LeftPanel, 'push');
            app.FMLIButton.Text = 'F (MLI)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'parula')
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.TitleLabel = uilabel(app.RightPanel);
            app.TitleLabel.Text = 'Title';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMLI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end