classdef nk_PrintResults2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        ExportMenuItem         matlab.ui.container.Menu
        ExportPredictionsMenu  matlab.ui.container.Menu
        ExportPerformanceMenu  matlab.ui.container.Menu
        PrintMenuItem          matlab.ui.container.Menu
        CloseMenuItem          matlab.ui.container.Menu
        CompModels             matlab.ui.container.Menu
        CompModelsStats        matlab.ui.container.Menu
        CompModelsVisual       matlab.ui.container.Menu
        selModal               matlab.ui.control.DropDown
        selLabel               matlab.ui.control.DropDown
        popupmenu1             matlab.ui.control.DropDown
        selModelMeasures       matlab.ui.control.DropDown
        selCVoocv              matlab.ui.control.DropDown
        selAnalysis            matlab.ui.control.DropDown
        pnBinary               matlab.ui.container.Panel
        selSubGroupOOCV        matlab.ui.control.DropDown
        thisMLIresult          matlab.ui.control.Button
        pnBinRegrPerfCmd       matlab.ui.container.Panel
        tglClrSwp              matlab.ui.control.StateButton
        selYaxis               matlab.ui.control.DropDown
        tglPercRank            matlab.ui.control.StateButton
        selCase                matlab.ui.control.DropDown
        tglSort                matlab.ui.control.StateButton
        txtSubgroupSet         matlab.ui.control.EditField
        cmdSubgroupClear       matlab.ui.control.Button
        cmdSubgroupSet         matlab.ui.control.Button
        cmdResetX              matlab.ui.control.Button
        cmdUpdateX             matlab.ui.control.Button
        txtXAxisName           matlab.ui.control.EditField
        cmdExportAxes1         matlab.ui.control.Button
        cmdExportScores        matlab.ui.control.Button
        pnContigCmds           matlab.ui.container.Panel
        cmdExportAxes20        matlab.ui.control.Button
        cmdExportCobWeb        matlab.ui.control.Button
        cmdMetricExport        matlab.ui.control.Button
        axes5                  matlab.ui.control.UIAxes
        axes20                 matlab.ui.control.UIAxes
        pnPieCmds              matlab.ui.container.Panel
        cmdExportPies          matlab.ui.control.Button
        txtPretestProb         matlab.ui.control.EditField
        axes4                  matlab.ui.control.UIAxes
        axes3                  matlab.ui.control.UIAxes
        pnRocCmds              matlab.ui.container.Panel
        selOneVsAll_Info       matlab.ui.control.DropDown
        cmdExportAxes2         matlab.ui.control.Button
        txtBinarize            matlab.ui.control.EditField
        axes2                  matlab.ui.control.UIAxes
        axes38                 matlab.ui.control.UIAxes
        axes1                  matlab.ui.control.UIAxes
        pnStartup              matlab.ui.container.Panel
        lbStartup              matlab.ui.control.Label
        pnVisual               matlab.ui.container.Panel
        spiderPlotButton       matlab.ui.control.Button
        selPager               matlab.ui.control.DropDown
        selVisMeas2            matlab.ui.control.DropDown
        selVisMeas             matlab.ui.control.DropDown
        selModality            matlab.ui.control.DropDown
        tglVisMeas2            matlab.ui.control.StateButton
        txtThrVisMeas2         matlab.ui.control.EditField
        txtAlterFeats          matlab.ui.control.EditField
        txtPager               matlab.ui.control.EditField
        cmdExportFeatFig       matlab.ui.control.Button
        cmdExportFeats         matlab.ui.control.Button
        tglSortFeat            matlab.ui.control.StateButton
        pn3DView               matlab.ui.container.Panel
        axes26                 matlab.ui.control.UIAxes
        axes27                 matlab.ui.control.UIAxes
        axes28                 matlab.ui.control.UIAxes
        axes33                 matlab.ui.control.UIAxes
        pnModelPerf            matlab.ui.container.Panel
        selSubParam            matlab.ui.control.DropDown
        cmdExportModelPerfC1   matlab.ui.control.Button
        cmdExportModelPerfC2   matlab.ui.control.Button
        cmdExportModelPerfVec  matlab.ui.control.Button
        axes37                 matlab.ui.control.UIAxes
        axes35                 matlab.ui.control.UIAxes
        axes17                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function MenuItemFeatures_Callback(app, hObject, eventdata, figure_handle)
            
            handles = guidata(figure_handle);
            PrintFromMenuClick(app, hObject, handles, 'export_features')
        end
        
        function MenuItemPerfTabulatur_Callback(app, hObject, eventdata, figure_handle)
            
            handles = guidata(figure_handle);
            display_tablegenerator(handles);
        end
        
        function MenuItemPerformance_Callback(app, hObject, eventdata, figure_handle)
            
            handles = guidata(figure_handle);
            PrintFromMenuClick(app, hObject, handles, 'export_performance')
        end
        
        function MenuItemPredictions_Callback(app, hObject, eventdata, figure_handle)
            
            handles = guidata(figure_handle);
            PrintFromMenuClick(app, hObject, handles, 'export_scores')
        end
        
        function OpenMenuItem_Callback(app, hObject, eventdata, handles)
            % --------------------------------------------------------------------
            
            % hObject    handle to OpenMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            file = uigetfile('*.fig');
            if ~isequal(file, 0)
                open(file);
            end
        end
        
        function PrintFromMenuClick(app, hObject, handles, ExportFunc)
            
            curranal = handles.selAnalysis.Value;
            if strcmp(hObject.Tag,'All')
                for i=1:numel(handles.NM.analysis)
                    try
                        analind = i;
                        handles.curranal = analind;
                        handles = switch_analysis(handles);
                        fprintf('\nProcessing analysis %g [ ID: %s ]',i, handles.NM.analysis{i}.id);
                        feval(ExportFunc,handles,1);
                        handles.curranal = curranal;  handles = switch_analysis(handles);
                        guidata(handles.figure1,handles);
                    catch
                        warning('\nProcessing of analysis %g [ ID: %s ] failed',i, handles.NM.analysis{i}.id);
                    end
                end
            else
                analind = str2double(hObject.Tag);
                if analind ~= handles.curranal
                    curranal = handles.curranal;
                    handles.curranal = analind;
                    handles = switch_analysis(handles);
                end
                fprintf('\nProcessing analysis %g [ ID: %s ]',analind, handles.NM.analysis{analind}.id);
                feval(ExportFunc,handles,1);
                if curranal ~= handles.curranal
                    handles.curranal = curranal;  handles = switch_analysis(handles);
                end
                guidata(handles.figure1,handles);
            end
        end
        
        function clickCallback(app, src, evt)
            
                %handles = guidata(gcf);
                %handles=[];
                % Grab the x & y axes coordinate where the mouse is
                axesHdl = get(gcf,'CurrentAxes');
                mousePoint = get(axesHdl, 'CurrentPoint');
                figdata = get(axesHdl,'UserData');
                handles = guidata(gcf);
                if ~isempty(figdata)
            
                    mouseX = mousePoint(1,1);
                    mouseY = mousePoint(1,2);
            
                    % Compare where data and current mouse point to find the data point
                    % which is closest to the mouse point
                    distancesToMouse = hypot(figdata.x - mouseX, figdata.y - mouseY);
                    [~, indpat] = min(abs(distancesToMouse));
            
                    % If the distance is less than some threshold, set the text
                    % object's string to show the data at that point.
                    xrange = nk_Range(get(axesHdl, 'Xlim'),2);
                    yrange = nk_Range(get(axesHdl, 'Ylim'),2);
                    if abs(mouseX - figdata.x(indpat)) < 0.035*xrange && abs(mouseY - figdata.y(indpat)) < 0.035*yrange
                        x1 = figdata.x(indpat);
                        y1 = figdata.y(indpat);
                        axes(handles.axes1);
                        if isfield(figdata,'cases')
                            selCase = figdata.cases{indpat};
                            if isfield(handles,'caseplot'), delete(handles.caseplot); end
                            handles.caseplot = plot(x1,y1,'ko','MarkerSize',20, 'LineWidth',1.5);
                            handles.selCase.Value = find(~cellfun(@isempty,strfind(handles.selCase.String,selCase)));
                        end
                        try
                            if isfield(handles, 'MLIdata') && ~isempty(handles.MLIdata)
                                handles.thisMLIresult.Visible = 'on';
                                handles.curCase = indpat;
                                if ~isnumeric(handles.MLIapp) && isvalid(handles.MLIapp)
                                    updateFcn(handles.MLIapp, handles)
                                end
                            else
                                handles.thisMLIresult.Visible = 'off';
                            end
                            if isfield(figdata,'cases'), axesHdl.Legend.String{end} = selCase; end
                        catch
                        end
                    end
                    set(axesHdl,'UserData',figdata);
                    guidata(handles.figure1,handles);
                end
        end
        
        function [h_new, targ] = copyobj_subplot(app, obj, titl, targ, pos)
            
            if ~exist('targ','var') || isempty(targ),
                targ = figure;
            end
            
            if ~exist('pos','var')
                pos = [0.12 0.15 0.85 0.7];
            end
            
            h_new = copyobj(obj,targ);
            h_new(1).Position = pos;
            h_new(1).Title.String = titl;
        end
        
        function hoverCallback(app, src, evt)
            
                % Grab the x & y axes coordinate where the mouse is
                axesHdl = get(gcf,'CurrentAxes');
                mousePoint = get(axesHdl, 'CurrentPoint');
                figdata = get(axesHdl,'UserData');
                mousePoint2 = get(gcf,'CurrentPoint');
                if ~isempty(figdata)
            
                    mouseX = mousePoint(1,1);
                    mouseY = mousePoint(1,2);
            
                    % Compare where data and current mouse point to find the data point
                    % which is closest to the mouse point
                    distancesToMouse = hypot(figdata.x - mouseX, figdata.y - mouseY);
                    [~, indpat] = min(abs(distancesToMouse));
            
                    % If the distance is less than some threshold, set the text
                    % object's string to show the data at that point.
                    xrange = nk_Range(get(axesHdl, 'Xlim'),2);
                    yrange = nk_Range(get(axesHdl, 'Ylim'),2);
                    if abs(mouseX - figdata.x(indpat)) < 0.035*xrange && abs(mouseY - figdata.y(indpat)) < 0.035*yrange
                        figdata.textHdl.String = {figdata.patterntext{indpat}};
                        figdata.hPanel.Position(1) = mousePoint2(1);
                        figdata.hPanel.Position(2) = mousePoint2(2);% * (figdata.pnpos(4)/figdata.figpos(4));
                        figdata.hPanel.Visible='on';
                        figdata.textHdl.Visible='on';
                    else
                        figdata.hPanel.Visible='off';
                    end
                    set(axesHdl,'UserData',figdata);
            
                end
        end
        
        function pushbutton1_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton1.
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1);
            cla;
        end
        
        function txtXAxis_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to txtXAxis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function nk_PrintResults2_OpeningFcn(app, varargin)
            % --- Executes just before nk_PrintResults2 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to nk_PrintResults2 (see VARARGIN)
            
            % Choose default command line output for nk_PrintResults2
            handles.output = hObject;
            handles.pnStartup.Position = handles.pnBinary.Position;
            hObject.Units = 'normalized';
            % Update handles structure
            guidata(hObject, handles);
            % This sets up the initial plot - only do when we are invisible
            % so window can get raised using nk_PrintResults2.
            if strcmp(get(hObject,'Visible'),'off')
                axes(handles.axes1); cla
                plot(rand(5));
            end
            fld = fieldnames(handles);
            for i=1:numel(fld)
                if isprop(handles.(fld{i}),'Units')
                    handles.(fld{i}).Units = 'normalized';
                end
            end
            handles.pnContigCmds.Units='normalized';
            set(gcf, 'WindowButtonMotionFcn', @app.hoverCallback)
            set(gcf, 'WindowButtonDownFcn', @app.clickCallback)
            %set(gcf,'Units','Normalized','Outerposition',[0 0 0.9 0.9]);
            handles.lbStartup.String = 'Initializing NM Results Viewer ...';
            handles.colpt               = 'o';
            handles.colptin             = getNMcolors;
            handles.colpl               = '.';
            handles.colpx               = '*';
            handles.TitleSize           = 20;
            handles.TitleWeight         = 'bold';
            handles.AxisLabelSize       = 16;
            handles.AxisLabelWeight     = 'bold';
            handles.AxisTickSize        = 15;
            handles.AxisTickWeight      = 'bold';
            handles.AxisLineWidth       = 1.5;
            handles.ZeroLineWidth       = 1.5;
            handles.ErrorMarkerSize     = 12;
            handles.ErrorMarkerWidth    = 0.25;
            handles.DataMarkerSize      = 10;
            handles.DataMarkerWidth     = 2.0;
            handles.DataMissMarkerSize  = 12;
            handles.DataMissMarkerWidth = 1;
            handles.RocAxisLabelSize    = 0.15;
            handles.RocAxisLabelWeight  = 'bold';
            handles.RocAxisTickSize     = 0.15;
            handles.RocAxisTickWeight   = 'bold';
            handles.RocAxisLineWidth    = 1.5;
            handles.PieAxisTickSize     = 0.15;
            handles.PieAxisTickWeight   = 'bold';
            handles.LegendFontSize      = 10;
            handles.axes1pos_orig       = [ 0.09 0.09 0.37 0.95 ];
            handles.axes1pos_alt        = [ 0.09 0.40 0.37 0.47 ];
            set(handles.axes1,'Position', handles.axes1pos_orig);
            set(handles.axes1, 'FontUnits','normalized','FontSize', 0.03, ...
                        'FontWeight', handles.AxisTickWeight, ...
                        'LineWidth', handles.AxisLineWidth);
            set(handles.axes38, ...%'FontSize', handles.AxisTickSize, ...
                        'FontWeight', handles.AxisTickWeight, ...
                        'LineWidth', handles.AxisLineWidth);
            set(handles.axes2,  'FontUnits','normalized','FontSize', 0.06, ...
                        'FontWeight', handles.RocAxisTickWeight, ...
                        'LineWidth', handles.RocAxisLineWidth);
            set(handles.axes3, ...%'FontSize', handles.PieAxisTickSize, ...
                        'FontWeight', handles.PieAxisTickWeight);
            set(handles.axes4,...%'FontSize', handles.PieAxisTickSize, ...
                        'FontWeight', handles.PieAxisTickWeight);
            set(handles.axes17, 'FontUnits','normalized','FontSize', 0.03, ...
                        'FontWeight', handles.AxisTickWeight, ...
                        'LineWidth', handles.AxisLineWidth);
            
            %% Load analysis
            NM = []; analind = 1; sz = get(0,'ScreenSize'); handles.screensize = sz(3:4);
            if handles.screensize(1) < 800
                handles.FontSizeDynamic = 9;
            elseif handles.screensize(1) < 1024
                handles.FontSizeDynamic = 10;
            elseif handles.screensize(1) < 1280
                handles.FontSizeDynamic = 10.5;
            elseif handles.screensize(1) < 1400
                handles.FontSizeDynamic = 11;
            else
                handles.FontSizeDynamic = 11.5;
            end
            nVarIn = length(varargin);
            for i = 1:nVarIn
               if strcmpi(varargin{i}, 'AnalysisIndex')
                   analind = varargin{i+1};
               elseif strcmpi(varargin{i}, 'NM')
                   NM = varargin{i+1};
               end
            end
            
            if isempty(NM)
                try
                    NM = evalin('base', 'NM');
                catch
                    errordlg('No NM structure found in MATLAB workspace!')
                end
            end
            
            % Read-in NM structure
            handles.NM    = NM; clear NM
            guidata(handles.figure1,handles)
            
            % Populate Analysis Selector
            handles = load_selAnalysis(handles);
            
            handles.curranal = analind;
            
            % in case alternative label was used, set label and mode
            handles.label = handles.NM.analysis{1,analind}.params.label.label;
            handles.modeflag = handles.NM.analysis{1,analind}.params.label.modeflag;
            
            if size(handles.label,2) > 1
                handles.multilabel = true;
            else
                handles.multilabel = false;
                handles.curlabel = 1;
            end
            handles.oocvview = false;
            handles.lbStartup.String = 'Retrieve OOCV results if available ...';
            handles.OOCVinfo = nk_GetOOCVInfo(handles.NM,'analysis');
            handles.lbStartup.String = 'Retrieve Training/CV results ...';
            handles = perf_display_app(handles);
            guidata(handles.figure1,handles);
            handles.pnStartup.Visible='off';
            if isfield(handles,'ExportPredictionsM')
               for i=1:numel(handles.ExportPredictionsM)
                    handles.ExportPredictionsM(i).Callback = {@app.MenuItemPredictions_Callback, hObject};
                    handles.ExportPerformanceM(i).Callback = {@app.MenuItemPerformance_Callback, hObject};
               end
               handles.ExportPredictionsDlg.Callback = {@app.MenuItemPerfTabulatur_Callback, hObject};
            end
            
            if handles.n_analyses>1
                handles.CompModels.Enable = 'on';
            else
                handles.CompModels.Enable = 'off';
            end
            
            if isfield(handles,'ExportFeaturesM')
                for i=1:numel(handles.ExportFeaturesM)
                    handles.ExportFeaturesM(i).Callback = {@app.MenuItemFeatures_Callback, hObject};
                end
            end
            handles.pnStartup.Visible='off';
        end

        % Menu selected function: CloseMenuItem
        function CloseMenuItem_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CloseMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selection = questdlg(['Quit ' get(handles.figure1,'Name') '?'],...
                                 ['Quit' get(handles.figure1,'Name') '...'],...
                                 'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            
            delete(handles.figure1)
        end

        % Menu selected function: CompModelsStats
        function CompModelsStats_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CompModelsStats (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            display_comparator(handles, 'stats');
            drawnow
        end

        % Menu selected function: CompModelsVisual
        function CompModelsVisual_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CompModelsVisual (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            display_comparator(handles, 'visual');
        end

        % Menu selected function: PrintMenuItem
        function PrintMenuItem_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PrintMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            export_fig(handles.pnBinary,'-painters','Classification_Plot.png')
        end

        % Button pushed function: cmdExportAxes1
        function cmdExportAxes1_Callback(app, event)
            % --- Executes on button press in cmdExportAxes1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportAxes1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            titl = handles.selYaxis.String{handles.selYaxis.Value};
            if strcmp(handles.axes38.Visible,'on')
                h_md_anal = [h_src handles.axes38];
            else
                h_md_anal = [];
            end
            copyobj_subplot(app, [handles.axes1 handles.legend_classplot h_md_anal], titl);
        end

        % Button pushed function: cmdExportAxes20
        function cmdExportAxes20_Callback(app, event)
            % --- Executes on button press in cmdExportAxes20.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportAxes20 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            titl = 'Confusion matrix';
            h_ax = copyobj_subplot(app, handles.axes20, titl, [], [0.2 0.15 0.6 0.75] );
            h_ax.Title.FontWeight = 'bold';
            colormap(flipud(gray));
        end

        % Button pushed function: cmdExportAxes2
        function cmdExportAxes2_Callback(app, event)
            % --- Executes on button press in cmdExportAxes2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportAxes2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            titl = 'ROC analysis';
            h_ax = copyobj_subplot(app, handles.axes2, titl, [], [0.2 0.15 0.6 0.75] );
            h_ax.Title.FontWeight = 'bold';
        end

        % Button pushed function: cmdExportCobWeb
        function cmdExportCobWeb_Callback(app, event)
            % --- Executes on button press in cmdExportCobWeb.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportCobWeb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hcobweb = copyobj_subplot(app, handles.axes5, [], [], [0.2 0.10 0.6 0.75] );
        end

        % Button pushed function: cmdExportFeatFig
        function cmdExportFeatFig_Callback(app, event)
            % --- Executes on button press in cmdExportFeatFig.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportFeatFig (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %if handles.sel
            
            titl = handles.selVisMeas.String{handles.selVisMeas.Value};
            h_ax = copyobj_subplot(app, handles.axes33, titl, [], [0.2 0.15 0.6 0.75] );
            h_ax.Title.FontWeight = 'bold';
        end

        % Button pushed function: cmdExportFeats
        function cmdExportFeats_Callback(app, event)
            % --- Executes on button press in cmdExportFeats.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportFeats (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            export_features(handles, 0);
        end

        % Button pushed function: cmdExportModelPerfC1
        function cmdExportModelPerfC1_Callback(app, event)
            % --- Executes on button press in cmdExportModelPerfC1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportModelPerfC1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            titl = 'Mean model performance across CV1 partitions';
            h_ax = copyobj_subplot(app, handles.axes35, titl, [], [0.2 0.25 0.6 0.6] );
            h_ax.Title.FontWeight = 'bold';
            xlabel('Folds')
            h_c = colorbar(h_ax,'location','SouthOutside');
            h_c.Position(2) = 0.07; h_c.Position(4) = .05;
        end

        % Button pushed function: cmdExportModelPerfC2
        function cmdExportModelPerfC2_Callback(app, event)
            % --- Executes on button press in cmdExportModelPerfC2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportModelPerfC2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            titl = 'Mean model performance across CV2 partitions';
            h_ax = copyobj_subplot(app, handles.axes37, titl, [], [0.2 0.25 0.6 0.6] );
            h_ax.Title.FontWeight = 'bold';
            xlabel('Folds')
            h_c=colorbar(h_ax,'location','SouthOutside');
            h_c.Position(2) = 0.07; h_c.Position(4) = .05;
        end

        % Button pushed function: cmdExportModelPerfVec
        function cmdExportModelPerfVec_Callback(app, event)
            % --- Executes on button press in cmdExportModelPerfVec.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportModelPerfVec (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            titl = 'Model performance across parameter combinations';
            [h_ax, h_fig] = copyobj_subplot(app, [handles.axes17 handles.legend_modelperf], titl, [], [0.15 0.15 0.75 0.75] );
            h_ax(1).Title.FontWeight = 'bold';
            h_fig.Units = 'normalized';
            h_fig.Position = [0.1 0.1 0.5 0.7];
        end

        % Button pushed function: cmdExportPies
        function cmdExportPies_Callback(app, event)
            % --- Executes on button press in cmdExportPies.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportPies (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Prepare the pie chart descriptors
            switch handles.modeflag
                case 'classification'
                    h_class = get(handles.popupmenu1,'Value');
                    h_onevsall_val  = get(handles.selOneVsAll_Info,'Value');
                    h_classlist     = get(handles.popupmenu1,'String');
                    if strcmpi(h_classlist{h_class},'Multi-group classifier') && h_onevsall_val > 1
                        colind = h_onevsall_val-1;
                        groupnames = {handles.NM.groupnames{colind}, 'REST'};
                        cl1 = handles.colptin{colind};
                        cl2 = [0.6 0.6 0.6];
                    else
                         groupnames = handles.BinClass{h_class}.groupnames;
                        cl1 = handles.colptin(handles.BinClass{h_class}.groupind(1),:);
                        cl2 = handles.colptin(handles.BinClass{h_class}.groupind(2),:);
                    end
                case 'regression'
                    thesh = get(handles.txtBinarize,'String');
                    groupnames{1} = sprintf('Group 1 (>=%s)',thesh);
                    groupnames{2} = sprintf('Group 2 (< %s)',thesh);
                    cl1 = [0.5 0.5 0.5]; cl2 = [0.5 0.5 0.5];
            end
            
            hpie = copyobj_subplot(app, handles.axes3, [], [], [0.2 0.15 0.6 0.75] );
            hpie.Title.String = sprintf('Prognostic gain: %s',groupnames{1});
            hpie.Title.Position(2)=1.2;
            hp = findobj(hpie, 'Type', 'patch');
            if numel(hp)==2,
                set(hp(2), 'FaceColor', cl1, 'FaceAlpha', 0.3);
            end
            set(hp(1), 'FaceColor', cl1, 'FaceAlpha', 1);
            
            
            hpie = copyobj_subplot(app, handles.axes4, [], [], [0.2 0.15 0.6 0.75] );
            hpie.Title.String = sprintf('Prognostic gain: %s',groupnames{2});
            hpie.Title.Position(2)=1.2;
            hp = findobj(hpie, 'Type', 'patch');
            if numel(hp)==2,
                set(hp(2), 'FaceColor', cl2,'FaceAlpha', 0.3);
            end
            set(hp(1), 'FaceColor', cl2, 'FaceAlpha', 1);
        end

        % Button pushed function: cmdExportScores
        function cmdExportScores_Callback(app, event)
            % --- Executes on button press in cmdExportScores.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdExportScores (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            export_scores(handles, 0);
        end

        % Button pushed function: cmdMetricExport
        function cmdMetricExport_Callback(app, event)
            % --- Executes on button press in cmdMetricExport.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdMetricExport (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            export_performance(handles, 0);
        end

        % Button pushed function: cmdResetX
        function cmdResetX_Callback(app, event)
            % --- Executes on button press in cmdResetX.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdResetX (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isfield(handles,'XaxisName')
                switch handles.modeflag
                    case 'classification'
                        for h = 1:handles.nclass
                            handles.BinClass{h} = rmfield(handles.BinClass{h},'Xaxis');
                        end
                    case 'regression'
                        handles.Regr = rmfield(handles.Regr,'Xaxis');
                end
                handles = rmfield(handles,'XaxisName');
                handles = display_main_app(handles);
                guidata(handles.figure1,handles);
            end
        end

        % Button pushed function: cmdSubgroupClear
        function cmdSubgroupClear_Callback(app, event)
            % --- Executes on button press in cmdSubgroupClear.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdSubgroupClear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isfield(handles,'SubIndex'), handles = rmfield(handles,'SubIndex'); end
            handles = perf_display_app(handles);
            guidata(handles.figure1,handles);
        end

        % Button pushed function: cmdSubgroupSet
        function cmdSubgroupSet_Callback(app, event)
            % --- Executes on button press in cmdSubgroupSet.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdSubgroupSet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                % Get alternative X values from workspace
                I = evalin('base',char(get(handles.txtSubgroupSet,'String'))); [nI, mI] = size(I);
                if mI>1, errordlg('You entered a matrix, but a vector is required'); end
                if ~islogical(I), errordlg('A logical vector is required.'); end
                if handles.oocvview
                    nC = numel(handles.OOCVinfo.Analyses{handles.curranal}.cases{handles.oocvind});
                else
                    nC = size(handles.label,1);
                end
                if nI ~= nC, errordlg(sprintf('The logical subgroup vector must have %g entries.',nC)); end
                handles.SubIndex = I;
                handles = perf_display_app(handles);
                guidata(handles.figure1,handles);
            catch ERR
                errordlg(sprintf('Error: %s!',ERR.message))
            end
        end

        % Button pushed function: cmdUpdateX
        function cmdUpdateX_Callback(app, event)
            % --- Executes on button press in cmdUpdateX.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdUpdateX (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            try
                % Get alternative X values from workspace
                Xvalues = evalin('base',char(get(handles.txtXAxisName,'String')));
                if ~isequal(size(Xvalues),size(handles.labels))
                    errordlg('Wrong size of X value vector')
                else
                    switch handles.modeflag
                        case 'classification'
                            for h = 1:handles.nclass
                                handles.BinClass{h}.Xaxis = Xvalues(handles.BinClass{h}.ind);
                                handles.BinClass{h}.Xaxis = handles.BinClass{h}.Xaxis(handles.BinClass{h}.sortind);
                            end
                        case 'regression'
                            handles.Regr.Xaxis = Xvalues;
                    end
                    handles.XaxisName = char(get(handles.txtXAxisName,'String'));
                    handles = display_main_app(handles);
                    guidata(handles.figure1, handles);
                end
            catch
                errordlg(sprintf('Could not load workspace variable %s not found!',char(get(handles.txtXAxisName,'String'))));
            end
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            
            handles = display_main_app(handles);
            guidata(handles.figure1,handles)
        end

        % Value changed function: selAnalysis
        function selAnalysis_Callback(app, event)
            % --- Executes on selection change in selAnalysis.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selAnalysis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selAnalysis contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selAnalysis
            
            % Load analysis with pre-specified handle into main figure memory
            
            handles = perf_display_app(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selCVoocv
        function selCVoocv_Callback(app, event)
            % --- Executes on selection change in selCVoocv.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selCVoocv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selCVoocv contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selCVoocv
            
            handles = display_main_app(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selCase
        function selCase_Callback(app, event)
            % --- Executes on selection change in selCase.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selCase (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selCase contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selCase
            contents = cellstr(get(hObject,'String'));
            selCase = hObject.String{hObject.Value};
            selCaseX2 = strsplit(selCase,' | ');
            selCaseX = selCaseX2{2};
            CaseId = str2num(contents{get(hObject,'Value')}) ;
            AxesData = handles.axes1.UserData;
            I = find(~cellfun(@isempty,strfind(AxesData.cases,selCaseX)));
            x1 = AxesData.x(I);
            y1 = AxesData.y(I);
            figpos = dsxy2figxy(handles.axes1,x1,y1);
            axes(handles.axes1);
            %[figx,figy] = dsxy2figxy(handles.axes1,[x1 y1],[x2 y2]);
            if isfield(handles,'caseplot'), delete(handles.caseplot); end
            handles.caseplot = plot(x1,y1,'ko','MarkerSize',20, 'LineWidth',1.5);
            if isfield(handles, 'MLIdata') && ~isempty(handles.MLIdata)
                handles.thisMLIresult.Visible = 'on';
                handles.curCase = handles.selCase.Value;
            
                if ~isnumeric(handles.MLIapp) && isvalid(handles.MLIapp)
                    updateFcn(handles.MLIapp, handles)
                end
            else
                handles.thisMLIresult.Visible = 'off';
            end
            % if strcmp(handles.axes1.Legend.Interpreter,'latex')
            %     handles.axes1.Legend.Interpreter = 'none';
            % end
            handles.axes1.Legend.String{end} = selCaseX;
            guidata(handles.figure1,handles);
        end

        % Value changed function: selLabel
        function selLabel_Callback(app, event)
            % --- Executes on selection change in selLabel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selLabel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selLabel contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selLabel
            handles = perf_display_app(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selModal
        function selModal_Callback(app, event)
            % --- Executes on selection change in selModal.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selModal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selModal contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selModal
            
            handles = perf_display_app(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selModality
        function selModality_Callback(app, event)
            % --- Executes on selection change in selModality.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selModality (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selModality contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selModality
            
            handles.curmodal = hObject.Value;
            load_selModality(handles)
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selModelMeasures
        function selModelMeasures_Callback(app, event)
            % --- Executes on selection change in selModelMeasures.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selModelMeasures (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selModelMeasures contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selModelMeasures
            handles = display_main_app(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selOneVsAll_Info
        function selOneVsAll_Info_Callback(app, event)
            % --- Executes on selection change in selOneVsAll_Info.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selOneVsAll_Info (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selOneVsAll_Info contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selOneVsAll_Info
            
            %% Display ROC
            
            handles = sel_onevsone(handles, hObject);
            handles = display_multiclassplot(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selPager
        function selPager_Callback(app, event)
            % --- Executes on selection change in selPager.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selPager (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selPager contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selPager
            
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selSubGroupOOCV
        function selSubGroupOOCV_Callback(app, event)
            % --- Executes on selection change in selSubGroupOOCV.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selSubGroupOOCV (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selSubGroupOOCV contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selSubGroupOOCV
            [handles, oocvind] = get_oocvind(handles);
            g_oocvind = handles.OOCVinfo.Analyses{handles.curranal}.OOCVvec(oocvind);
            if hObject.Value > 1
                gindex = hObject.Value-1;
                if ~islogical(handles.NM.OOCV{g_oocvind}.groups)
                    groups = nk_MakeDummyVariables(handles.NM.OOCV{g_oocvind}.groups);
                else
                    groups = handles.NM.OOCV{g_oocvind}.groups;
                end
                if isfield(handles.NM.OOCV{g_oocvind},'refgroup')
                    gindex = [gindex handles.NM.OOCV{g_oocvind}.refgroup];
                end
                handles.SubIndex = any(groups(:,gindex),2);
                switch handles.modeflag
                    case 'classification'
                        if isfield(handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.BinResults{handles.curlabel}.Group{gindex(1)},'PermAnal')
                            handles.PermAnal = handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.BinResults{handles.curlabel}.Group{gindex(1)}.PermAnal.ModelPermSignificance(handles.curclass);
                        else
                            if isfield(handles,'PermAnal'), handles = rmfield(handles,'PermAnal'); end
                        end
                    case 'regression'
                        if isfield(handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.RegrResults{handles.curlabel}.Group{gindex(1)},'PermAnal')
                            handles.PermAnal = handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.RegrResults{handles.curlabel}.Group{gindex(1)}.PermAnal.ModelPermSignificance;
                        else
                            if isfield(handles,'PermAnal'), handles = rmfield(handles,'PermAnal'); end
                        end
                end
            else
                if isfield(handles,'SubIndex')
                    handles = rmfield(handles,'SubIndex');
                end
                switch handles.modeflag
                    case 'classification'
                        if isfield(handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.BinResults{1},'PermAnal')
                            handles.PermAnal = handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.BinResults{1}.PermAnal.ModelPermSignificance(handles.curclass);
                        else
                            if isfield(handles,'PermAnal'), handles = rmfield(handles,'PermAnal'); end
                        end
                    case 'regression'
                        if isfield(handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.RegrResults{1},'PermAnal')
                            handles.PermAnal = handles.NM.analysis{handles.curranal}.OOCV{g_oocvind}.RegrResults{1}.PermAnal.ModelPermSignificance;
                        else
                            if isfield(handles,'PermAnal'), handles = rmfield(handles,'PermAnal'); end
                        end
                end
            end
            switch handles.modeflag
                case 'classification'
                    handles = display_classplot_oocv(handles.curclass, handles);
                case 'regression'
                    handles.oocvview = false;
                    handles  = display_regrplot(handles, [], false, true, false, 0.2);
                    handles.oocvview = true;
                    handles  = display_regrplot(handles, [], true, false, true, 0.8);
                    load_selCase(handles,handles.OOCVinfo.Analyses{handles.curranal}.cases{handles.oocvind});
            end
            guidata(handles.figure1,handles);
        end

        % Value changed function: selSubParam
        function selSubParam_Callback(app, event)
            % --- Executes on selection change in selSubParam.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selSubParam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selSubParam contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selSubParam
            
            if handles.curmodal <= numel(handles.params.TrainParam.FUSION.M)
                handles = display_SubParam(handles);
                guidata(handles.figure1,handles);
            end
        end

        % Value changed function: selVisMeas2
        function selVisMeas2_Callback(app, event)
            % --- Executes on selection change in selVisMeas2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selVisMeas2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selVisMeas2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selVisMeas2
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selVisMeas
        function selVisMeas_Callback(app, event)
            % --- Executes on selection change in selVisMeas.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selVisMeas (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selVisMeas contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selVisMeas
            
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: selYaxis
        function selYaxis_Callback(app, event)
            % --- Executes on selection change in selYaxis.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selYaxis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns selYaxis contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from selYaxis
            
            handles = perf_display_app(handles);
            guidata(handles.figure1, handles);
        end

        % Button pushed function: spiderPlotButton
        function spiderPlotButton_Callback(app, event)
            % --- Executes on button press in spiderPlotButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to spiderPlotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            appSpiderPlot(handles);
        end

        % Value changed function: tglClrSwp
        function tglClrSwp_Callback(app, event)
            % --- Executes on button press in tglClrSwp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tglClrSwp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of tglClrSwp
            if handles.oocvview
                handles = display_classplot_oocv(handles.curclass, handles);
            else
                handles = display_classplot(handles.curclass, handles);
            end
            guidata(handles.figure1,handles);
        end

        % Value changed function: tglPercRank
        function tglPercRank_Callback(app, event)
            % --- Executes on button press in tglPercRank.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tglPercRank (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of tglPercRank
            if handles.oocvview
                handles = display_classplot_oocv(handles.curclass, handles);
            else
                handles = display_classplot(handles.curclass, handles);
            end
            guidata(handles.figure1,handles);
        end

        % Value changed function: tglSortFeat
        function tglSortFeat_Callback(app, event)
            % --- Executes on button press in tglSortFeat.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tglSortFeat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of tglSortFeat
            
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: tglSort
        function tglSort_Callback(app, event)
            % --- Executes on button press in tglSort.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tglSort (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of tglSort
            handles = perf_display_app(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: tglVisMeas2
        function tglVisMeas2_Callback(app, event)
            % --- Executes on button press in tglVisMeas2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tglVisMeas2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of tglVisMeas2
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Button pushed function: thisMLIresult
        function thisMLIresult_Callback(app, event)
            % --- Executes on button press in thisMLIresult.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to thisMLIresult (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %copyobj_subplot([handles.axes1 handles.legend_classplot h_md_anal], titl);
            
            %barMLI(handles, handles.curCase);%, handles.MLIdata.Label.BinResults.Modality, ...
                    %handles.visdata{1,1}.params.features, ...
                    %handles.NM.Y{1}, ...
                    %0);
                    if isnumeric(handles.MLIapp)
                        handles.MLIapp = appMLI(handles);
                    elseif ~isvalid(handles.MLIapp)
                        handles.MLIapp.delete;
                        handles.MLIapp  = appMLI(handles);
                    elseif ~isnumeric(handles.MLIapp) && isvalid(handles.MLIapp)
                        updateFcn(handles.MLIapp, handles);
                    end
                guidata(handles.figure1,handles);
        end

        % Value changed function: txtAlterFeats
        function txtAlterFeats_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to txtAlterFeats (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of txtAlterFeats as text
            %        str2double(get(hObject,'String')) returns contents of txtAlterFeats as a double
            
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: txtBinarize
        function txtBinarize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to txtBinarize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of txtBinarize as text
            %        str2double(get(hObject,'String')) returns contents of txtBinarize as a double
            display_main_app(handles)
            guidata(handles.figure1,handles);
        end

        % Value changed function: txtPager
        function txtPager_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to txtPager (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of txtPager as text
            %        str2double(get(hObject,'String')) returns contents of txtPager as a double
            
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end

        % Value changed function: txtPretestProb
        function txtPretestProb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to txtPretestProb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of txtPretestProb as text
            %        str2double(get(hObject,'String')) returns contents of txtPretestProb as a double
            pretest = str2double(char(get(hObject,'String')));
            h_class = get(handles.popupmenu1,'Value');
            display_piecharts(handles, pretest);
        end

        % Value changed function: txtThrVisMeas2
        function txtThrVisMeas2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to txtThrVisMeas2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of txtThrVisMeas2 as text
            %        str2double(get(hObject,'String')) returns contents of txtThrVisMeas2 as a double
            handles = display_visual(handles);
            guidata(handles.figure1,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'NM Results Manager';
            app.figure1.Tag = 'figure1';
            app.FileMenu = uimenu(app.figure1);
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'FileMenu';
            app.ExportMenuItem = uimenu(app.FileMenu);
            app.ExportMenuItem.Text = 'Export NM Results ...';
            app.ExportMenuItem.Tag = 'ExportMenuItem';
            app.ExportPredictionsMenu = uimenu(app.ExportMenuItem);
            app.ExportPredictionsMenu.Text = 'Export Predictions';
            app.ExportPredictionsMenu.Tag = 'ExportPredictionsMenu';
            app.ExportPerformanceMenu = uimenu(app.ExportMenuItem);
            app.ExportPerformanceMenu.Text = 'Export Performance Metrics';
            app.ExportPerformanceMenu.Tag = 'ExportPerformanceMenu';
            app.PrintMenuItem = uimenu(app.FileMenu);
            app.PrintMenuItem.MenuSelectedFcn = createCallbackFcn(app, @PrintMenuItem_Callback, true);
            app.PrintMenuItem.Text = 'Print Current View';
            app.PrintMenuItem.Tag = 'PrintMenuItem';
            app.CloseMenuItem = uimenu(app.FileMenu);
            app.CloseMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuItem_Callback, true);
            app.CloseMenuItem.Separator = 'on';
            app.CloseMenuItem.Text = 'Quit NM Results Viewer';
            app.CloseMenuItem.Tag = 'CloseMenuItem';
            app.CompModels = uimenu(app.figure1);
            app.CompModels.Text = 'Compare';
            app.CompModels.Tag = 'CompModels';
            app.CompModelsStats = uimenu(app.CompModels);
            app.CompModelsStats.MenuSelectedFcn = createCallbackFcn(app, @CompModelsStats_Callback, true);
            app.CompModelsStats.Text = 'Statistical Comparisons';
            app.CompModelsStats.Tag = 'CompModelsStats';
            app.CompModelsVisual = uimenu(app.CompModels);
            app.CompModelsVisual.MenuSelectedFcn = createCallbackFcn(app, @CompModelsVisual_Callback, true);
            app.CompModelsVisual.Text = 'Visual Comparisons';
            app.CompModelsVisual.Tag = 'CompModelsVisual';
            app.pnModelPerf = uipanel(app.figure1);
            app.pnModelPerf.Title = 'Model parameters';
            app.pnModelPerf.Tag = 'pnModelPerf';
            app.axes17 = uiaxes(app.pnModelPerf);
            app.axes17.Tag = 'axes17';
            app.axes35 = uiaxes(app.pnModelPerf);
            app.axes35.Tag = 'axes35';
            app.axes37 = uiaxes(app.pnModelPerf);
            app.axes37.Tag = 'axes37';
            app.cmdExportModelPerfVec = uibutton(app.pnModelPerf, 'push');
            app.cmdExportModelPerfVec.ButtonPushedFcn = createCallbackFcn(app, @cmdExportModelPerfVec_Callback, true);
            app.cmdExportModelPerfVec.Tag = 'cmdExportModelPerfVec';
            app.cmdExportModelPerfVec.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportModelPerfVec.Text = 'F';
            app.cmdExportModelPerfC2 = uibutton(app.pnModelPerf, 'push');
            app.cmdExportModelPerfC2.ButtonPushedFcn = createCallbackFcn(app, @cmdExportModelPerfC2_Callback, true);
            app.cmdExportModelPerfC2.Tag = 'cmdExportModelPerfC2';
            app.cmdExportModelPerfC2.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportModelPerfC2.Text = 'F';
            app.cmdExportModelPerfC1 = uibutton(app.pnModelPerf, 'push');
            app.cmdExportModelPerfC1.ButtonPushedFcn = createCallbackFcn(app, @cmdExportModelPerfC1_Callback, true);
            app.cmdExportModelPerfC1.Tag = 'cmdExportModelPerfC1';
            app.cmdExportModelPerfC1.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportModelPerfC1.Text = 'F';
            app.selSubParam = uidropdown(app.pnModelPerf);
            app.selSubParam.Items = {'Pop-up Menu'};
            app.selSubParam.ValueChangedFcn = createCallbackFcn(app, @selSubParam_Callback, true);
            app.selSubParam.Tag = 'selSubParam';
            app.selSubParam.Value = 'Pop-up Menu';
            app.pnVisual = uipanel(app.figure1);
            app.pnVisual.Title = 'Visualization results';
            app.pnVisual.Tag = 'pnVisual';
            app.axes33 = uiaxes(app.pnVisual);
            app.axes33.Tag = 'axes33';
            app.pn3DView = uipanel(app.pnVisual);
            app.pn3DView.Visible = 'off';
            app.pn3DView.Tag = 'pn3DView';
            app.axes28 = uiaxes(app.pn3DView);
            app.axes28.Tag = 'axes28';
            app.axes28.Visible = 'off';
            app.axes27 = uiaxes(app.pn3DView);
            app.axes27.Tag = 'axes27';
            app.axes27.Visible = 'off';
            app.axes26 = uiaxes(app.pn3DView);
            app.axes26.Tag = 'axes26';
            app.axes26.Visible = 'off';
            app.tglSortFeat = uibutton(app.pnVisual, 'state');
            app.tglSortFeat.ValueChangedFcn = createCallbackFcn(app, @tglSortFeat_Callback, true);
            app.tglSortFeat.Tag = 'tglSortFeat';
            app.tglSortFeat.Tooltip = 'Push to sort features in descending order';
            app.tglSortFeat.Text = 'Sort features';
            app.cmdExportFeats = uibutton(app.pnVisual, 'push');
            app.cmdExportFeats.ButtonPushedFcn = createCallbackFcn(app, @cmdExportFeats_Callback, true);
            app.cmdExportFeats.Tag = 'cmdExportFeats';
            app.cmdExportFeats.Tooltip = 'Export feature data';
            app.cmdExportFeats.Text = 'E';
            app.cmdExportFeatFig = uibutton(app.pnVisual, 'push');
            app.cmdExportFeatFig.ButtonPushedFcn = createCallbackFcn(app, @cmdExportFeatFig_Callback, true);
            app.cmdExportFeatFig.Tag = 'cmdExportFeatFig';
            app.cmdExportFeatFig.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportFeatFig.Text = 'F';
            app.txtPager = uieditfield(app.pnVisual, 'text');
            app.txtPager.ValueChangedFcn = createCallbackFcn(app, @txtPager_Callback, true);
            app.txtPager.Tag = 'txtPager';
            app.txtPager.Tooltip = 'enter vector of features to be displayed';
            app.txtAlterFeats = uieditfield(app.pnVisual, 'text');
            app.txtAlterFeats.ValueChangedFcn = createCallbackFcn(app, @txtAlterFeats_Callback, true);
            app.txtAlterFeats.Tag = 'txtAlterFeats';
            app.txtAlterFeats.Tooltip = 'enter cell array of alternative feature descriptors';
            app.txtThrVisMeas2 = uieditfield(app.pnVisual, 'text');
            app.txtThrVisMeas2.ValueChangedFcn = createCallbackFcn(app, @txtThrVisMeas2_Callback, true);
            app.txtThrVisMeas2.Tag = 'txtThrVisMeas2';
            app.txtThrVisMeas2.Tooltip = 'enter vector of features to be displayed';
            app.tglVisMeas2 = uibutton(app.pnVisual, 'state');
            app.tglVisMeas2.ValueChangedFcn = createCallbackFcn(app, @tglVisMeas2_Callback, true);
            app.tglVisMeas2.Tag = 'tglVisMeas2';
            app.tglVisMeas2.Tooltip = 'Push to sort features in descending order';
            app.tglVisMeas2.Text = 'Filter features';
            app.selModality = uidropdown(app.pnVisual);
            app.selModality.Items = {'Select Modality'};
            app.selModality.ValueChangedFcn = createCallbackFcn(app, @selModality_Callback, true);
            app.selModality.Tag = 'selModality';
            app.selModality.Tooltip = 'Select Modality';
            app.selModality.Value = 'Select Modality';
            app.selVisMeas = uidropdown(app.pnVisual);
            app.selVisMeas.Items = {'Select Visualisation Measure'};
            app.selVisMeas.ValueChangedFcn = createCallbackFcn(app, @selVisMeas_Callback, true);
            app.selVisMeas.Tag = 'selVisMeas';
            app.selVisMeas.Tooltip = 'Select Visualisation Measure';
            app.selVisMeas.Value = 'Select Visualisation Measure';
            app.selVisMeas2 = uidropdown(app.pnVisual);
            app.selVisMeas2.Items = {'Select Visualization Measure'};
            app.selVisMeas2.ValueChangedFcn = createCallbackFcn(app, @selVisMeas2_Callback, true);
            app.selVisMeas2.Tag = 'selVisMeas2';
            app.selVisMeas2.Tooltip = 'Select Visualization Measure';
            app.selVisMeas2.Value = 'Select Visualization Measure';
            app.selPager = uidropdown(app.pnVisual);
            app.selPager.Items = {'Select Page of Features'};
            app.selPager.ValueChangedFcn = createCallbackFcn(app, @selPager_Callback, true);
            app.selPager.Tag = 'selPager';
            app.selPager.Tooltip = 'Select Page of Features';
            app.selPager.Value = 'Select Page of Features';
            app.spiderPlotButton = uibutton(app.pnVisual, 'push');
            app.spiderPlotButton.ButtonPushedFcn = createCallbackFcn(app, @spiderPlotButton_Callback, true);
            app.spiderPlotButton.Tag = 'spiderPlotButton';
            app.spiderPlotButton.Text = 'ROI mapping';
            app.pnStartup = uipanel(app.figure1);
            app.pnStartup.Tag = 'pnStartup';
            app.lbStartup = uilabel(app.pnStartup);
            app.lbStartup.Tag = 'lbStartup';
            app.lbStartup.Text = {'Starting NM Results Viewer.'; 'Please wait ....'};
            app.pnBinary = uipanel(app.figure1);
            app.pnBinary.Tag = 'pnBinary';
            app.axes1 = uiaxes(app.pnBinary);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes1.Tag = 'axes1';
            app.axes38 = uiaxes(app.pnBinary);
            app.axes38.XLim = [0 1];
            app.axes38.YLim = [0 1];
            app.axes38.ZLim = [0 1];
            app.axes38.CLim = [0 1];
            app.axes38.ALim = [0 1];
            app.axes38.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes38.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes38.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes38.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes38.ZTick = [0 0.5 1];
            app.axes38.ZTickLabel = '';
            app.axes38.Tag = 'axes38';
            app.axes38.Visible = 'off';
            app.pnRocCmds = uipanel(app.pnBinary);
            app.pnRocCmds.Title = 'ROC analysis';
            app.pnRocCmds.Tag = 'pnRocCmds';
            app.axes2 = uiaxes(app.pnRocCmds);
            app.axes2.Tag = 'axes2';
            app.txtBinarize = uieditfield(app.pnRocCmds, 'text');
            app.txtBinarize.ValueChangedFcn = createCallbackFcn(app, @txtBinarize_Callback, true);
            app.txtBinarize.Tag = 'txtBinarize';
            app.txtBinarize.Tooltip = 'Provide threshold to binarize continuous targets';
            app.txtBinarize.Value = 'Binarization Threshold';
            app.cmdExportAxes2 = uibutton(app.pnRocCmds, 'push');
            app.cmdExportAxes2.ButtonPushedFcn = createCallbackFcn(app, @cmdExportAxes2_Callback, true);
            app.cmdExportAxes2.Tag = 'cmdExportAxes2';
            app.cmdExportAxes2.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportAxes2.Text = 'F';
            app.selOneVsAll_Info = uidropdown(app.pnRocCmds);
            app.selOneVsAll_Info.Items = {'Pop-up Menu'};
            app.selOneVsAll_Info.ValueChangedFcn = createCallbackFcn(app, @selOneVsAll_Info_Callback, true);
            app.selOneVsAll_Info.Tag = 'selOneVsAll_Info';
            app.selOneVsAll_Info.Value = 'Pop-up Menu';
            app.pnPieCmds = uipanel(app.pnBinary);
            app.pnPieCmds.Title = 'Prognostic Gains';
            app.pnPieCmds.Tag = 'pnPieCmds';
            app.axes3 = uiaxes(app.pnPieCmds);
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.pnPieCmds);
            app.axes4.Tag = 'axes4';
            app.txtPretestProb = uieditfield(app.pnPieCmds, 'text');
            app.txtPretestProb.ValueChangedFcn = createCallbackFcn(app, @txtPretestProb_Callback, true);
            app.txtPretestProb.Tag = 'txtPretestProb';
            app.txtPretestProb.Tooltip = 'Type here an alternative pre-test probability if the class distribution is not representative.';
            app.txtPretestProb.Value = 'Enter P0';
            app.cmdExportPies = uibutton(app.pnPieCmds, 'push');
            app.cmdExportPies.ButtonPushedFcn = createCallbackFcn(app, @cmdExportPies_Callback, true);
            app.cmdExportPies.Tag = 'cmdExportPies';
            app.cmdExportPies.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportPies.Text = 'F';
            app.pnContigCmds = uipanel(app.pnBinary);
            app.pnContigCmds.Title = 'Performance Metrics';
            app.pnContigCmds.Tag = 'pnContigCmds';
            app.axes20 = uiaxes(app.pnContigCmds);
            app.axes20.Tag = 'axes20';
            app.axes20.Visible = 'off';
            app.axes5 = uiaxes(app.pnContigCmds);
            app.axes5.Tag = 'axes5';
            app.axes5.Visible = 'off';
            app.cmdMetricExport = uibutton(app.pnContigCmds, 'push');
            app.cmdMetricExport.ButtonPushedFcn = createCallbackFcn(app, @cmdMetricExport_Callback, true);
            app.cmdMetricExport.Tag = 'cmdMetricExport';
            app.cmdMetricExport.Tooltip = 'Export performance metrics';
            app.cmdMetricExport.Text = 'E';
            app.cmdExportCobWeb = uibutton(app.pnContigCmds, 'push');
            app.cmdExportCobWeb.ButtonPushedFcn = createCallbackFcn(app, @cmdExportCobWeb_Callback, true);
            app.cmdExportCobWeb.Tag = 'cmdExportCobWeb';
            app.cmdExportCobWeb.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportCobWeb.Text = 'F';
            app.cmdExportAxes20 = uibutton(app.pnContigCmds, 'push');
            app.cmdExportAxes20.ButtonPushedFcn = createCallbackFcn(app, @cmdExportAxes20_Callback, true);
            app.cmdExportAxes20.Tag = 'cmdExportAxes20';
            app.cmdExportAxes20.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportAxes20.Text = 'F';
            app.cmdExportScores = uibutton(app.pnBinary, 'push');
            app.cmdExportScores.ButtonPushedFcn = createCallbackFcn(app, @cmdExportScores_Callback, true);
            app.cmdExportScores.Tag = 'cmdExportScores';
            app.cmdExportScores.Tooltip = 'Export scores';
            app.cmdExportScores.Text = 'E';
            app.cmdExportAxes1 = uibutton(app.pnBinary, 'push');
            app.cmdExportAxes1.ButtonPushedFcn = createCallbackFcn(app, @cmdExportAxes1_Callback, true);
            app.cmdExportAxes1.Tag = 'cmdExportAxes1';
            app.cmdExportAxes1.Tooltip = 'Extract chart into separate figure for user editing';
            app.cmdExportAxes1.Text = 'F';
            app.pnBinRegrPerfCmd = uipanel(app.pnBinary);
            app.pnBinRegrPerfCmd.Tag = 'pnBinRegrPerfCmd';
            app.txtXAxisName = uieditfield(app.pnBinRegrPerfCmd, 'text');
            app.txtXAxisName.Tag = 'txtXAxisName';
            app.txtXAxisName.Tooltip = 'Enter the name of an variable (double) in the MATLAB workspace that defines alternative X values in the classification plot';
            app.cmdUpdateX = uibutton(app.pnBinRegrPerfCmd, 'push');
            app.cmdUpdateX.ButtonPushedFcn = createCallbackFcn(app, @cmdUpdateX_Callback, true);
            app.cmdUpdateX.Tag = 'cmdUpdateX';
            app.cmdUpdateX.Text = 'Set';
            app.cmdResetX = uibutton(app.pnBinRegrPerfCmd, 'push');
            app.cmdResetX.ButtonPushedFcn = createCallbackFcn(app, @cmdResetX_Callback, true);
            app.cmdResetX.Tag = 'cmdResetX';
            app.cmdResetX.Text = 'Reset';
            app.cmdSubgroupSet = uibutton(app.pnBinRegrPerfCmd, 'push');
            app.cmdSubgroupSet.ButtonPushedFcn = createCallbackFcn(app, @cmdSubgroupSet_Callback, true);
            app.cmdSubgroupSet.Tag = 'cmdSubgroupSet';
            app.cmdSubgroupSet.Text = 'Set';
            app.cmdSubgroupClear = uibutton(app.pnBinRegrPerfCmd, 'push');
            app.cmdSubgroupClear.ButtonPushedFcn = createCallbackFcn(app, @cmdSubgroupClear_Callback, true);
            app.cmdSubgroupClear.Tag = 'cmdSubgroupClear';
            app.cmdSubgroupClear.Text = 'Reset';
            app.txtSubgroupSet = uieditfield(app.pnBinRegrPerfCmd, 'text');
            app.txtSubgroupSet.Tag = 'txtSubgroupSet';
            app.txtSubgroupSet.Tooltip = 'Enter the name of a logical vector in the MATLAB workspace indexing a subgroup of subjects in the current analysis';
            app.tglSort = uibutton(app.pnBinRegrPerfCmd, 'state');
            app.tglSort.ValueChangedFcn = createCallbackFcn(app, @tglSort_Callback, true);
            app.tglSort.Tag = 'tglSort';
            app.tglSort.Tooltip = 'Sort scores in ascending order';
            app.tglSort.Text = 'Sort';
            app.selCase = uidropdown(app.pnBinRegrPerfCmd);
            app.selCase.Items = {'Pop-up Menu'};
            app.selCase.ValueChangedFcn = createCallbackFcn(app, @selCase_Callback, true);
            app.selCase.Tag = 'selCase';
            app.selCase.Tooltip = 'Select case from dropdown list';
            app.selCase.Value = 'Pop-up Menu';
            app.tglPercRank = uibutton(app.pnBinRegrPerfCmd, 'state');
            app.tglPercRank.ValueChangedFcn = createCallbackFcn(app, @tglPercRank_Callback, true);
            app.tglPercRank.Tag = 'tglPercRank';
            app.tglPercRank.Tooltip = 'Transform score to percentile ranks';
            app.tglPercRank.Text = '%-Ranks';
            app.selYaxis = uidropdown(app.pnBinRegrPerfCmd);
            app.selYaxis.Items = {'Pop-up Menu'};
            app.selYaxis.ValueChangedFcn = createCallbackFcn(app, @selYaxis_Callback, true);
            app.selYaxis.Tag = 'selYaxis';
            app.selYaxis.Tooltip = 'Choose between different model outputs';
            app.selYaxis.Value = 'Pop-up Menu';
            app.tglClrSwp = uibutton(app.pnBinRegrPerfCmd, 'state');
            app.tglClrSwp.ValueChangedFcn = createCallbackFcn(app, @tglClrSwp_Callback, true);
            app.tglClrSwp.Tag = 'tglClrSwp';
            app.tglClrSwp.Tooltip = 'Swap colors of classification plot';
            app.tglClrSwp.Text = 'Swap ';
            app.thisMLIresult = uibutton(app.pnBinary, 'push');
            app.thisMLIresult.ButtonPushedFcn = createCallbackFcn(app, @thisMLIresult_Callback, true);
            app.thisMLIresult.Tag = 'thisMLIresult';
            app.thisMLIresult.Visible = 'off';
            app.thisMLIresult.Tooltip = 'Open MLI results for this case';
            app.thisMLIresult.Text = 'I';
            app.selSubGroupOOCV = uidropdown(app.pnBinary);
            app.selSubGroupOOCV.Items = {'Subgroup selection'};
            app.selSubGroupOOCV.ValueChangedFcn = createCallbackFcn(app, @selSubGroupOOCV_Callback, true);
            app.selSubGroupOOCV.Tag = 'selSubGroupOOCV';
            app.selSubGroupOOCV.Visible = 'off';
            app.selSubGroupOOCV.Tooltip = 'Select case from dropdown list';
            app.selSubGroupOOCV.Value = 'Subgroup selection';
            app.selAnalysis = uidropdown(app.figure1);
            app.selAnalysis.Items = {'Pop-up Menu'};
            app.selAnalysis.ValueChangedFcn = createCallbackFcn(app, @selAnalysis_Callback, true);
            app.selAnalysis.Tag = 'selAnalysis';
            app.selAnalysis.Value = 'Pop-up Menu';
            app.selCVoocv = uidropdown(app.figure1);
            app.selCVoocv.Items = {'Pop-up Menu'};
            app.selCVoocv.ValueChangedFcn = createCallbackFcn(app, @selCVoocv_Callback, true);
            app.selCVoocv.Tag = 'selCVoocv';
            app.selCVoocv.Value = 'Pop-up Menu';
            app.selModelMeasures = uidropdown(app.figure1);
            app.selModelMeasures.Items = {'Pop-up Menu'};
            app.selModelMeasures.ValueChangedFcn = createCallbackFcn(app, @selModelMeasures_Callback, true);
            app.selModelMeasures.Tag = 'selModelMeasures';
            app.selModelMeasures.Value = 'Pop-up Menu';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Pop-up Menu'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Pop-up Menu';
            app.selLabel = uidropdown(app.figure1);
            app.selLabel.Items = {'Pop-up Menu'};
            app.selLabel.ValueChangedFcn = createCallbackFcn(app, @selLabel_Callback, true);
            app.selLabel.Tag = 'selLabel';
            app.selLabel.Tooltip = 'Classifier selector';
            app.selLabel.Value = 'Pop-up Menu';
            app.selModal = uidropdown(app.figure1);
            app.selModal.Items = {'Pop-up Menu'};
            app.selModal.ValueChangedFcn = createCallbackFcn(app, @selModal_Callback, true);
            app.selModal.Tag = 'selModal';
            app.selModal.Tooltip = 'Select modality (late fusion) ';
            app.selModal.Value = 'Pop-up Menu';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nk_PrintResults2_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)nk_PrintResults2_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end