classdef simSample_App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SampleSizeSimulatorUIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        priorTab                        matlab.ui.container.Tab
        repetitionsperhyperparameterEditField  matlab.ui.control.NumericEditField
        repetitionsperhyperparameterEditFieldLabel  matlab.ui.control.Label
        eventrateEditField              matlab.ui.control.EditField
        eventrateEditFieldLabel         matlab.ui.control.Label
        ratioofpredictivemarkersEditField  matlab.ui.control.EditField
        ratioofpredictivemarkersEditFieldLabel  matlab.ui.control.Label
        NfeaturesEditField              matlab.ui.control.EditField
        NfeaturesEditField_2Label       matlab.ui.control.Label
        ExportfigureButton              matlab.ui.control.Button
        MLmethodDropDown                matlab.ui.control.DropDown
        MLmethodDropDownLabel           matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        NcasesEditField                 matlab.ui.control.EditField
        NcasesEditFieldLabel            matlab.ui.control.Label
        fractionofmissingfeaturespercaseEditField  matlab.ui.control.NumericEditField
        fractionofmissingfeaturespercaseEditFieldLabel  matlab.ui.control.Label
        caseswithmissingvaluesEditField  matlab.ui.control.NumericEditField
        caseswithmissingvaluesEditFieldLabel  matlab.ui.control.Label
        BatchPercEditField              matlab.ui.control.NumericEditField
        BatchEditFieldLabel             matlab.ui.control.Label
        NbatchesEditField               matlab.ui.control.NumericEditField
        NbatchesEditFieldLabel          matlab.ui.control.Label
        minAUCEditField                 matlab.ui.control.NumericEditField
        minAUCLabel                     matlab.ui.control.Label
        maxAUCEditField                 matlab.ui.control.NumericEditField
        maxAUCEditFieldLabel            matlab.ui.control.Label
        postTab                         matlab.ui.container.Tab
        filenameEditField               matlab.ui.control.EditField
        filenameEditFieldLabel          matlab.ui.control.Label
        ExportParametersButton          matlab.ui.control.Button
        AnalysisListBox                 matlab.ui.control.ListBox
        AnalysistosimulateListBoxLabel  matlab.ui.control.Label
        SetupButton                     matlab.ui.control.Button
        TextField_SimulationOverview    matlab.ui.control.TextArea
        SimulateButton_post             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        simAppAux
        data % Description
        loadedNMData
        add2orig = 0 %add data to original 1 or 0
        NSims = 1
        NGroups 
        NperGroupRound = 0 
        simGrid
        simPlanGrid
        NReps = 1
        dummycodedSites = 0
        simCond = -1
        condName 
     
    end    
    
    properties (Access = public)
        NM
    end

    
    methods (Access = private)
        function [app, popuplist, popupitems] = PopulateAnalysesDropDown(app)
            
            popuplist = cell(1, numel(app.NM.analysis));
            for i=1:numel(app.NM.analysis)
                popuplist{i} = sprintf('%g: %s', i, app.NM.analysis{i}.id);
            end
            popupitems = 1:numel(app.NM.analysis);
        end

    end
    
    methods (Access = public)
        
        function updateSimPlan(app, simGrid, add2orig, NReps, sites, simCond)
            
            %app.simPlanGrid = uitable(app.postTab, 'Data', simGrid, 'Position', [270,50, 200,130]);
            if simCond == 0
                condName = 'label';
                text = sprintf('SIMULATION PLAN: \n%d ROUNDS, %d REPS PER ROUND\nGroups defined by %s\n',size(simGrid,2), NReps, condName);
            
            elseif simCond > 0
                condName = app.NM.covnames(simCond);
                text = sprintf('SIMULATION PLAN: \n%d ROUNDS, %d REPS PER ROUND\nGroups defined by %s\n',size(simGrid,2), NReps, condName{1});
            
            else 
                text = sprintf('SIMULATION PLAN: \n%d ROUNDS, %d REPS PER ROUND\n',size(simGrid,2), NReps);
            
            end 
            for i=1:size(simGrid,1)
                line = sprintf('Group %d: ', i);
                for j=1:size(simGrid,2)
                    line = sprintf('%s %d', line, simGrid(i,j));
                end
                text = sprintf('%s\n', text, line);
            end

            if add2orig
                text = sprintf('%s\nsimulated data will be added to original data', text);
            end
            

            app.TextField_SimulationOverview.Value = text;

            app.simGrid = simGrid;
            app.add2orig = add2orig;

            app.SetupButton.Enable = 'on';
            app.SimulateButton_post.Enable = 'on';
            app.NSims = size(simGrid,2);
            app.NReps = NReps;
            app.dummycodedSites = sites;
            app.simCond = simCond; 
            
            app.NGroups = size(simGrid,1);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin > 1
                app.NM = varargin(1);
                app.NM = app.NM{1};
            

                [app, app.AnalysisListBox.Items, app.AnalysisListBox.ItemsData] = PopulateAnalysesDropDown(app);
                
                app.SimulateButton_post.Enable = 'off';

                
                %app.NM_label = app.NM.label;
                app.loadedNMData = 1;
                app.TextField_SimulationOverview.Visible = 1;
                % n groups to simulate from
                app.NGroups = 1;
            else 
                app.loadedNMData = 0;
                app.postTab.HandleVisibility.Visible = 'off';
            end
            drawnow
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            algorithms = {'LINKERNSVM', 'LINSVM', 'L2LR', 'L1LR', 'L1SVC'};
            IN.Nfeats = str2num(app.NfeaturesEditField.Value);
            IN.Nmarkers = str2num(app.ratioofpredictivemarkersEditField.Value);
            IN.Ncases = str2num(app.NcasesEditField.Value);
            IN.eventprob = str2num(app.eventrateEditField.Value);
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.caseswithmissingvaluesEditField.Value;
            IN.NfeatsMiss = app.fractionofmissingfeaturespercaseEditField.Value;
            IN.algorithm = algorithms{app.MLmethodDropDown.Value};
            
            IN.NReps = app.repetitionsperhyperparameterEditField.Value;
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML(IN, app.UIAxes);
            %app.ResultsTextArea = evalc(simSamp);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.withlabelDropDown.Value;
            switch value
                case 'posthoc'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditField_2Label.Visible = 'off';
                case 'prior'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditField_2Label.Visible = 'off';
            end
        end

        % Callback function
        function SelectButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            app.DatasetNoEditField.Value = fullfile(path, file);
            
            app.data = readtable(fullfile(path,file));
            app.TextArea.Visible = 'on';
            %infotext = {};
            infotext = sprintf('Dataset: \nN features: %d \nN observations: %d\n\n', ...
                size(app.data,2), size(app.data,1));
            if isempty(app.LabelvariableEditField.Value)
                varinfo = sprintf('Variable names:\n');
                for i = 1:numel(app.data.Properties.VariableNames)
                    varinfo = [varinfo, sprintf('%s\n', ...
                        app.data.Properties.VariableNames{1,i})];
                end
                infotext = [infotext, varinfo];

                %else
             %   n_groups = 
             %   infotext = {sprintf()}
            end

            
            app.TextArea.Value = infotext;
            app.LabelvariableEditField.Editable = 'on';
            app.ExcludeCheckBox.Enable = 'on';
       
        end

        % Callback function
        function ExcludeCheckBoxValueChanged(app, event)
            value = app.ExcludeCheckBox.Value;
            if value
                app.ExcludeEditField.Visible = 'on';
            else 
                app.ExcludeEditField.Visible = 'off';
            end
        end

        % Button pushed function: ExportfigureButton
        function ExportfigureButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.UIAxes,fullfile(filepath,filename));
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: SimulateButton_post
        function SimulateButton_posthocPushed(app, event)
            if app.loadedNMData
                M = app.NM.analysis{1,app.AnalysisListBox.Value}.params.TrainParam.FUSION.M;
                for i = 1:length(M)
                    feat{1,i} = app.NM.Y{1,i};
                end
                feat = app.NM.Y{1,M};
                labels = app.NM.label;
            else
                fulldata = app.data;
                if ~isempty(app.ExcludeEditField.Value)
                    fulldata = removevars(fulldata,app.ExcludeEditField.Value);
                end
                feat = removevars(fulldata,app.LabelvariableEditField.Value);
                labels = eval(sprintf('data.%s', app.LabelvariableEditField.Value));
            end


            IN.Data = feat;
            IN.Modalities = M;
            IN.DataLabel= labels;
            IN.Nfeats = size(IN.Data,2);
            IN.Ncases = app.simGrid;
            IN.NRanalysis = app.AnalysisListBox.Value;
            IN.verbose = 0;
            IN.add2orig = app.add2orig;
            IN.NReps = app.NReps;
            IN.SitesIdx = app.dummycodedSites;
            IN.condIdx = app.simCond;

%             IN.Nmarkers = app.predictivemarkersEditField.Value; %unused
%             IN.eventprob = app.EventrateEditField.Value; %unused
%             IN.AUCmax = app.maxAUCEditField.Value; % unused
%             IN.AUCmin = app.minAUCEditField.Value; % unused
%             IN.Nbatches = app.NbatchesEditField.Value; % unused
%             IN.BatchPerc = app.BatchPercEditField.Value; % unused
%             IN.NcasesMiss = app.EventrateEditField.Value; % unused
%             IN.NfeatsMiss = app.EventrateEditField.Value; % unused
%             %IN.algorithm =

            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML(IN, app.UIAxes);
            app.ExportfigureButton.Visible = 'on';
            
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            app.SimulateButton_post.Enable = 'off';
            app.SetupButton.Enable = 'off';
            if isfield(app.NM, 'covars')
                covarsNames = app.NM.covnames;
            else 
                covarsNames = [];
            end
            app.simAppAux = simAppAux(app, app.NSims, app.NGroups, app.simGrid, app.add2orig, app.NReps, covarsNames, app.simCond);
        end

        % Close request function: SampleSizeSimulatorUIFigure
        function SampleSizeSimulatorUIFigureCloseRequest(app, event)
            delete(app.simAppAux)
            delete(app)
            
        end

        % Button pushed function: ExportParametersButton
        function ExportParametersButtonPushed(app, event)
            if app.loadedNMData
                M = app.NM.analysis{1,app.AnalysisListBox.Value}.params.TrainParam.FUSION.M;
                for i = 1:length(M)
                    feat{1,i} = app.NM.Y{1,i};
                end
                feat = app.NM.Y{1,M};
                labels = app.NM.label;
            else
                fulldata = app.data;
                if ~isempty(app.ExcludeEditField.Value)
                    fulldata = removevars(fulldata,app.ExcludeEditField.Value);
                end
                feat = removevars(fulldata,app.LabelvariableEditField.Value);
                labels = eval(sprintf('data.%s', app.LabelvariableEditField.Value));
            end
            
            IN.Data = feat;
            IN.Modalities = M;
            IN.DataLabel= labels;
            IN.Nfeats = size(IN.Data,2);
            IN.Ncases = app.simGrid;
            IN.NRanalysis = app.AnalysisListBox.Value;
            IN.verbose = 0;
            IN.add2orig = app.add2orig;
            IN.NReps = app.NReps;
            IN.SitesIdx = app.dummycodedSites;
            IN.condIdx = app.simCond;


            if ~isempty(app.filenameEditField.Value)
                save(app.filenameEditField.Value, 'IN');
            else
                questdlg('Please enter filename first')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SampleSizeSimulatorUIFigure = uifigure('Visible', 'off');
            app.SampleSizeSimulatorUIFigure.Name = 'Sample Size Simulator';
            app.SampleSizeSimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @SampleSizeSimulatorUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.SampleSizeSimulatorUIFigure);
            title(app.UIAxes, 'Sample size simulation')
            xlabel(app.UIAxes, 'Hyperparameters')
            ylabel(app.UIAxes, 'Balanced Accuracy [%]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TabGroup = uitabgroup(app.SampleSizeSimulatorUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.priorTab = uitab(app.TabGroup);
            app.priorTab.Title = 'prior';
            app.maxAUCEditFieldLabel = uilabel(app.priorTab);
            app.maxAUCEditFieldLabel.Text = 'max AUC';
            app.maxAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.maxAUCEditField.Tooltip = {'maximum separability per discriminative feature'};
            app.maxAUCEditField.Value = 0.7;
            app.minAUCLabel = uilabel(app.priorTab);
            app.minAUCLabel.Text = 'min AUC';
            app.minAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.minAUCEditField.Tooltip = {'minimum separability per discriminative feature'};
            app.minAUCEditField.Value = 0.5;
            app.NbatchesEditFieldLabel = uilabel(app.priorTab);
            app.NbatchesEditFieldLabel.Text = 'N batches';
            app.NbatchesEditField = uieditfield(app.priorTab, 'numeric');
            app.NbatchesEditField.Tooltip = {'Define number of batches (site or group effects)'};
            app.BatchEditFieldLabel = uilabel(app.priorTab);
            app.BatchEditFieldLabel.Text = 'Batch %';
            app.BatchPercEditField = uieditfield(app.priorTab, 'numeric');
            app.BatchPercEditField.Tooltip = {'Select batch effect variation as ratio of total feature variation'};
            app.BatchPercEditField.Value = 0.1;
            app.caseswithmissingvaluesEditFieldLabel = uilabel(app.priorTab);
            app.caseswithmissingvaluesEditFieldLabel.Text = 'cases with missing values';
            app.caseswithmissingvaluesEditField = uieditfield(app.priorTab, 'numeric');
            app.caseswithmissingvaluesEditField.Tooltip = {'ratio of cases with missing features in population [0-1]'};
            app.fractionofmissingfeaturespercaseEditFieldLabel = uilabel(app.priorTab);
            app.fractionofmissingfeaturespercaseEditFieldLabel.Text = 'fraction of missing features per case';
            app.fractionofmissingfeaturespercaseEditField = uieditfield(app.priorTab, 'numeric');
            app.fractionofmissingfeaturespercaseEditField.Tooltip = {'ratio of missing values per case with missing values [0-1]'};
            app.NcasesEditFieldLabel = uilabel(app.priorTab);
            app.NcasesEditFieldLabel.Text = 'N cases';
            app.NcasesEditField = uieditfield(app.priorTab, 'text');
            app.NcasesEditField.Value = '[100 200 300 400]';
            app.SimulateButton = uibutton(app.priorTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.MLmethodDropDownLabel = uilabel(app.priorTab);
            app.MLmethodDropDownLabel.Text = 'ML method';
            app.MLmethodDropDown = uidropdown(app.priorTab);
            app.MLmethodDropDown.Items = {'LIBSVM: Linear-kernel SVM', 'LIBLINEAR: L2-reg, L1-los SVC', 'LIBLINEAR: L2-reg. LR', 'LIBLINEAR: L1-reg. LR', 'LIBLINEAR: L1-reg. SVC'};
            app.MLmethodDropDown.ItemsData = [1 2 3 4 5];
            app.MLmethodDropDown.Value = 1;
            app.ExportfigureButton = uibutton(app.priorTab, 'push');
            app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfigureButtonPushed, true);
            app.ExportfigureButton.Visible = 'off';
            app.ExportfigureButton.Text = 'Export figure';
            app.NfeaturesEditField_2Label = uilabel(app.priorTab);
            app.NfeaturesEditField_2Label.Text = 'N features';
            app.NfeaturesEditField = uieditfield(app.priorTab, 'text');
            app.NfeaturesEditField.Tooltip = {'number of features (enter single value or vector)'};
            app.NfeaturesEditField.Value = '100';
            app.ratioofpredictivemarkersEditFieldLabel = uilabel(app.priorTab);
            app.ratioofpredictivemarkersEditFieldLabel.Text = 'ratio of predictive markers';
            app.ratioofpredictivemarkersEditField = uieditfield(app.priorTab, 'text');
            app.ratioofpredictivemarkersEditField.Tooltip = {'fraction of features which are predictive among all features (single value or range)'};
            app.ratioofpredictivemarkersEditField.Value = '0.2';
            app.eventrateEditFieldLabel = uilabel(app.priorTab);
            app.eventrateEditFieldLabel.Text = 'event rate';
            app.eventrateEditField = uieditfield(app.priorTab, 'text');
            app.eventrateEditField.Tooltip = {'ratio of group 1 (event group) vs. group 2 '};
            app.eventrateEditField.Value = '0.5';
            app.repetitionsperhyperparameterEditFieldLabel = uilabel(app.priorTab);
            app.repetitionsperhyperparameterEditFieldLabel.Text = 'repetitions per hyperparameter';
            app.repetitionsperhyperparameterEditField = uieditfield(app.priorTab, 'numeric');
            app.repetitionsperhyperparameterEditField.Value = 10;
            app.postTab = uitab(app.TabGroup);
            app.postTab.Title = 'post';
            app.SimulateButton_post = uibutton(app.postTab, 'push');
            app.SimulateButton_post.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_posthocPushed, true);
            app.SimulateButton_post.Text = 'Simulate';
            app.TextField_SimulationOverview = uitextarea(app.postTab);
            app.TextField_SimulationOverview.Editable = 'off';
            app.TextField_SimulationOverview.Enable = 'off';
            app.SetupButton = uibutton(app.postTab, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Change simulation plan';
            app.AnalysistosimulateListBoxLabel = uilabel(app.postTab);
            app.AnalysistosimulateListBoxLabel.Tooltip = {'Select analysis to simulate from the list'};
            app.AnalysistosimulateListBoxLabel.Text = 'Analysis to simulate';
            app.AnalysisListBox = uilistbox(app.postTab);
            app.ExportParametersButton = uibutton(app.postTab, 'push');
            app.ExportParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExportParametersButtonPushed, true);
            app.ExportParametersButton.Text = 'Export parameters as simulation.mat';
            app.filenameEditFieldLabel = uilabel(app.postTab);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.postTab, 'text');
            % Show the figure after all components are created
            app.SampleSizeSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simSample_App2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SampleSizeSimulatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SampleSizeSimulatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SampleSizeSimulatorUIFigure)
        end
    end
end